classdef BMSApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BMSUIFigure                     matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        MILSimTime                      matlab.ui.control.TextArea
        StatusMessagesTextArea          matlab.ui.control.TextArea
        StatusTextAreaLabel             matlab.ui.control.Label
        RealtimeSimStartButton          matlab.ui.control.StateButton
        StopTimeEditField               slrealtime.ui.control.StopTimeEditField
        TabGroupMain                    matlab.ui.container.TabGroup
        Controls                        matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        ChargeImbalancemVLabel          matlab.ui.control.Label
        ModuleovercurrentLamp           matlab.ui.control.Lamp
        ModuleovercurrentLampLabel      matlab.ui.control.Label
        CellundervoltageLamp            matlab.ui.control.Lamp
        CellundervoltageLabel           matlab.ui.control.Label
        CellovervoltageLamp             matlab.ui.control.Lamp
        CellovervoltageLabel            matlab.ui.control.Label
        CelllowtemperatureLamp          matlab.ui.control.Lamp
        CelllowtemperatureLamp_11Label  matlab.ui.control.Label
        CellhightemperatureLamp         matlab.ui.control.Lamp
        CellhightemperatureLamp_11Label  matlab.ui.control.Label
        AmbientTemperatureCEditField    matlab.ui.control.NumericEditField
        AmbientTemperatureCLabel        matlab.ui.control.Label
        TestScenarioDropDown            matlab.ui.control.DropDown
        TestScenarioDropDown_11Label    matlab.ui.control.Label
        BatteryModulueCurrentEditField_2  matlab.ui.control.NumericEditField
        ChargeLabel                     matlab.ui.control.Label
        DischargeLabel                  matlab.ui.control.Label
        ALabel                          matlab.ui.control.Label
        VLabel                          matlab.ui.control.Label
        BatteryModulueVoltageEditField_2  matlab.ui.control.NumericEditField
        ChargeImbalanceEditField        matlab.ui.control.NumericEditField
        ChargeImbalancemVGauge          matlab.ui.control.SemicircularGauge
        SOCLabel                        matlab.ui.control.Label
        CurrentLimitsALabel             matlab.ui.control.Label
        SOCEditField_1                  matlab.ui.control.NumericEditField
        StateofChargeSOCGauge           matlab.ui.control.Gauge
        BMU                             matlab.ui.control.Image
        Slider                          matlab.ui.control.Slider
        Tank                            matlab.ui.control.Image
        Gauge_7                         matlab.ui.control.LinearGauge
        Gauge_6                         matlab.ui.control.LinearGauge
        SOC_TextArea_7                  matlab.ui.control.TextArea
        T_TextArea_7                    matlab.ui.control.TextArea
        V_TextArea_7                    matlab.ui.control.TextArea
        I_TextArea_7                    matlab.ui.control.TextArea
        SOC_TextArea_6                  matlab.ui.control.TextArea
        T_TextArea_6                    matlab.ui.control.TextArea
        V_TextArea_6                    matlab.ui.control.TextArea
        I_TextArea_6                    matlab.ui.control.TextArea
        Gauge_8                         matlab.ui.control.LinearGauge
        Gauge_5                         matlab.ui.control.LinearGauge
        SOC_TextArea_8                  matlab.ui.control.TextArea
        T_TextArea_8                    matlab.ui.control.TextArea
        V_TextArea_8                    matlab.ui.control.TextArea
        I_TextArea_8                    matlab.ui.control.TextArea
        SOC_TextArea_5                  matlab.ui.control.TextArea
        T_TextArea_5                    matlab.ui.control.TextArea
        V_TextArea_5                    matlab.ui.control.TextArea
        I_TextArea_5                    matlab.ui.control.TextArea
        SOC_TextArea_9                  matlab.ui.control.TextArea
        SOC_TextArea_10                 matlab.ui.control.TextArea
        SOC_TextArea_11                 matlab.ui.control.TextArea
        SOC_TextArea_12                 matlab.ui.control.TextArea
        T_TextArea_9                    matlab.ui.control.TextArea
        T_TextArea_10                   matlab.ui.control.TextArea
        T_TextArea_11                   matlab.ui.control.TextArea
        T_TextArea_12                   matlab.ui.control.TextArea
        V_TextArea_9                    matlab.ui.control.TextArea
        I_TextArea_9                    matlab.ui.control.TextArea
        V_TextArea_10                   matlab.ui.control.TextArea
        V_TextArea_11                   matlab.ui.control.TextArea
        V_TextArea_12                   matlab.ui.control.TextArea
        Gauge_9                         matlab.ui.control.LinearGauge
        I_TextArea_10                   matlab.ui.control.TextArea
        Gauge_10                        matlab.ui.control.LinearGauge
        I_TextArea_11                   matlab.ui.control.TextArea
        Gauge_11                        matlab.ui.control.LinearGauge
        I_TextArea_12                   matlab.ui.control.TextArea
        Gauge_12                        matlab.ui.control.LinearGauge
        Gauge_4                         matlab.ui.control.LinearGauge
        SOC_TextArea_4                  matlab.ui.control.TextArea
        T_TextArea_4                    matlab.ui.control.TextArea
        V_TextArea_4                    matlab.ui.control.TextArea
        I_TextArea_4                    matlab.ui.control.TextArea
        Gauge_3                         matlab.ui.control.LinearGauge
        Gauge_2                         matlab.ui.control.LinearGauge
        Gauge_1                         matlab.ui.control.LinearGauge
        SOC_TextArea_3                  matlab.ui.control.TextArea
        T_TextArea_3                    matlab.ui.control.TextArea
        V_TextArea_3                    matlab.ui.control.TextArea
        I_TextArea_3                    matlab.ui.control.TextArea
        SOC_TextArea_2                  matlab.ui.control.TextArea
        T_TextArea_2                    matlab.ui.control.TextArea
        SOC_TextArea_1                  matlab.ui.control.TextArea
        T_TextArea_1                    matlab.ui.control.TextArea
        V_TextArea_2                    matlab.ui.control.TextArea
        I_TextArea_2                    matlab.ui.control.TextArea
        V_TextArea_1                    matlab.ui.control.TextArea
        I_TextArea_1                    matlab.ui.control.TextArea
        ChargeDischargeCurrentAEditField  matlab.ui.control.NumericEditField
        underVoltage                    matlab.ui.control.StateButton
        overVoltage                     matlab.ui.control.StateButton
        lowTemp                         matlab.ui.control.StateButton
        highTemp                        matlab.ui.control.StateButton
        overCurrent                     matlab.ui.control.StateButton
        ConfigurationButton             matlab.ui.control.Button
        ChargeEditField                 matlab.ui.control.NumericEditField
        DischargeEditField              matlab.ui.control.NumericEditField
        BatteryModulueCurrentEditField  matlab.ui.control.NumericEditField
        BatteryModulueVoltageEditField  matlab.ui.control.NumericEditField
        ChargeGauge                     matlab.ui.control.Gauge
        DischargemAGauge                matlab.ui.control.Gauge
        FaultsdetectedLabel             matlab.ui.control.Label
        BatteryModuleCurrentAGauge_Label  matlab.ui.control.Label
        BatteryModuleVoltageVGauge      matlab.ui.control.Gauge
        BatteryModuleVoltageVGaugeLabel  matlab.ui.control.Label
        BatteryModuleCurrentAGauge      matlab.ui.control.Gauge
        UI                              matlab.ui.control.Image
        UIAxes                          matlab.ui.control.UIAxes
        ConfigurationTab                matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        OC_offButton_1                  matlab.ui.control.StateButton
        SC_offButton_1                  matlab.ui.control.StateButton
        OC_offButton_2                  matlab.ui.control.StateButton
        SC_offButton_2                  matlab.ui.control.StateButton
        OC_offButton_3                  matlab.ui.control.StateButton
        SC_offButton_3                  matlab.ui.control.StateButton
        OC_offButton_4                  matlab.ui.control.StateButton
        SC_offButton_4                  matlab.ui.control.StateButton
        OC_offButton_5                  matlab.ui.control.StateButton
        SC_offButton_5                  matlab.ui.control.StateButton
        OC_offButton_6                  matlab.ui.control.StateButton
        SC_offButton_6                  matlab.ui.control.StateButton
        SC_offButton_12                 matlab.ui.control.StateButton
        OC_offButton_12                 matlab.ui.control.StateButton
        SC_offButton_11                 matlab.ui.control.StateButton
        OC_offButton_11                 matlab.ui.control.StateButton
        SC_offButton_10                 matlab.ui.control.StateButton
        OC_offButton_10                 matlab.ui.control.StateButton
        SC_offButton_9                  matlab.ui.control.StateButton
        OC_offButton_9                  matlab.ui.control.StateButton
        SC_offButton_8                  matlab.ui.control.StateButton
        OC_offButton_8                  matlab.ui.control.StateButton
        SC_offButton_7                  matlab.ui.control.StateButton
        OC_offButton_7                  matlab.ui.control.StateButton
        V_offButton_12                  matlab.ui.control.StateButton
        T_offButton_12                  matlab.ui.control.StateButton
        V_offButton_11                  matlab.ui.control.StateButton
        T_offButton_11                  matlab.ui.control.StateButton
        V_offButton_10                  matlab.ui.control.StateButton
        T_offButton_10                  matlab.ui.control.StateButton
        V_offButton_9                   matlab.ui.control.StateButton
        T_offButton_9                   matlab.ui.control.StateButton
        V_offButton_8                   matlab.ui.control.StateButton
        T_offButton_8                   matlab.ui.control.StateButton
        V_offButton_7                   matlab.ui.control.StateButton
        T_offButton_7                   matlab.ui.control.StateButton
        V_offButton_6                   matlab.ui.control.StateButton
        T_offButton_6                   matlab.ui.control.StateButton
        V_offButton_5                   matlab.ui.control.StateButton
        T_offButton_5                   matlab.ui.control.StateButton
        V_offButton_4                   matlab.ui.control.StateButton
        T_offButton_4                   matlab.ui.control.StateButton
        V_offButton_3                   matlab.ui.control.StateButton
        T_offButton_3                   matlab.ui.control.StateButton
        V_offButton_2                   matlab.ui.control.StateButton
        T_offButton_2                   matlab.ui.control.StateButton
        V_offButton_1                   matlab.ui.control.StateButton
        T_offButton_1                   matlab.ui.control.StateButton
        V_EditField_12                  matlab.ui.control.EditField
        V_EditField_11                  matlab.ui.control.EditField
        V_EditField_10                  matlab.ui.control.EditField
        V_EditField_9                   matlab.ui.control.EditField
        V_EditField_8                   matlab.ui.control.EditField
        V_EditField_7                   matlab.ui.control.EditField
        V_EditField_6                   matlab.ui.control.EditField
        V_EditField_5                   matlab.ui.control.EditField
        V_EditField_4                   matlab.ui.control.EditField
        V_EditField_3                   matlab.ui.control.EditField
        V_EditField_2                   matlab.ui.control.EditField
        V_EditField_1                   matlab.ui.control.EditField
        T_EditField_12                  matlab.ui.control.EditField
        T_EditField_11                  matlab.ui.control.EditField
        T_EditField_10                  matlab.ui.control.EditField
        T_EditField_9                   matlab.ui.control.EditField
        T_EditField_8                   matlab.ui.control.EditField
        T_EditField_7                   matlab.ui.control.EditField
        T_EditField_6                   matlab.ui.control.EditField
        T_EditField_5                   matlab.ui.control.EditField
        T_EditField_4                   matlab.ui.control.EditField
        T_EditField_3                   matlab.ui.control.EditField
        T_EditField_2                   matlab.ui.control.EditField
        T_EditField_1                   matlab.ui.control.EditField
        Configuration                   matlab.ui.control.Image
        SystemLog                       slrealtime.ui.control.SystemLog
        DesktopSimStartButton           matlab.ui.control.StateButton
        UpdateButton                    slrealtime.ui.control.UpdateButton
        StatusBar                       slrealtime.ui.control.StatusBar
        LoadButton                      slrealtime.ui.control.LoadButton
        StopTimeLabel                   matlab.ui.control.Label
        SimulationModeButtonGroup       matlab.ui.container.ButtonGroup
        HardwareintheLoopButton         matlab.ui.control.RadioButton
        ModelintheLoopButton            matlab.ui.control.RadioButton
        RebootButton                    slrealtime.ui.control.RebootButton
        TargetSelector                  slrealtime.ui.control.TargetSelector
        ConnectButton                   slrealtime.ui.control.ConnectButton
    end

    
 properties (Access = private)
        myTimer                 % Timer object
        alarmDetected = -1      % Alarm/fault detection
        alarmDelay = 0          % Alarm/fault delay
 end

 methods (Access = private)          

     function TimerFcn(app,~,~)
         
         status = get_param('SystemModel',"SimulationStatus");         
         if status == "running" || status == "external"        
             update_Values(app);
         end
         
     end

     function disableComponents(app)
        app.StatusMessagesTextArea.Value = 'Please wait!';  
        app.BatteryModuleCurrentAGauge.Enable = 'off';
        app.BatteryModuleVoltageVGauge.Enable = 'off';
        app.ChargeGauge.Enable = 'off';
        app.DischargemAGauge.Enable = 'off';
        app.StateofChargeSOCGauge.Enable = 'off';
        app.ChargeImbalancemVGauge.Enable = 'off';
        app.UI.Enable = 'off';
        app.BMU.Enable = 'off';
     end

     function enableComponents(app)    
        app.BatteryModuleCurrentAGauge.Enable  = 'on';
        app.BatteryModuleVoltageVGauge.Enable ='on';
        app.ChargeGauge.Enable  = 'on';
        app.DischargemAGauge.Enable  = 'on';
        app.StateofChargeSOCGauge.Enable  = 'on';
        app.ChargeImbalancemVGauge.Enable = 'on';
        app.UI.Enable = 'on';
        app.BMU.Enable = 'on';
     end

     function update_Values(app)                   
         
         if isempty(str2double(extractBetween(get_param('SystemModel/User Interface/SOC_1','Value'),'"','"'))) || isnan(str2double(extractBetween(get_param('SystemModel/User Interface/SOC_1','Value'),'"','"')))             
             return
         end
         
         for cellNo = 1:12

             % Reading each cell value from model

             % Update SOC parameters for all Cells
             SOC_TextArea_ = ['SOC_TextArea_',num2str(cellNo)];
             SOC_Path = ['SystemModel/User Interface/SOC_',num2str(cellNo)];
             SOC_Gauge = ['Gauge_',num2str(cellNo)];

             % Update Temperature parameters for all Cells
             T_TextArea_ = ['T_TextArea_',num2str(cellNo)];
             T_Path = ['SystemModel/User Interface/T_',num2str(cellNo)];
                
             % Update Voltage parameters for all Cells
             V_TextArea_ = ['V_TextArea_',num2str(cellNo)];
             V_Path = ['SystemModel/User Interface/V_',num2str(cellNo)];            
                
             % Update Current parameters for all Cells
             I_TextArea_ = ['I_TextArea_',num2str(cellNo)];
             I_Path = ['SystemModel/User Interface/I_',num2str(cellNo)];

             app.(SOC_TextArea_).Value = extractBetween(get_param(SOC_Path,'Value'),'"','"');
             app.(SOC_Gauge).Value = str2double(extractBetween(get_param(SOC_Path,'Value'),'"','"'));
             app.(T_TextArea_).Value = extractBetween(get_param(T_Path,'Value'),'"','"');
             app.(V_TextArea_).Value = extractBetween(get_param(V_Path,'Value'),'"','"');
             app.(I_TextArea_).Value = extractBetween(get_param(I_Path,'Value'),'"','"');

             % Update UIAxes with simulation time and Currents
             sim_time = get_param('SystemModel','SimulationTime');
             plot(app.UIAxes,sim_time,str2double(app.(I_TextArea_).Value),LineStyle="--",LineWidth=1,Marker="_");
             hold(app.UIAxes, 'on');
         end

         if app.ModelintheLoopButton.Value == 1
             app.MILSimTime.Value = "T=" + sim_time;
         end

         % Read Component's values         
         app.StateofChargeSOCGauge.Value = str2double(extractBetween(get_param('SystemModel/User Interface/SOC','Value'),'"','"'));
         app.SOCEditField_1.Value = app.StateofChargeSOCGauge.Value;
         
         app.ChargeImbalancemVGauge.Value = str2double(extractBetween(get_param('SystemModel/User Interface/Charge_Imbalance','Value'),'"','"'));
         app.ChargeImbalanceEditField.Value = app.ChargeImbalancemVGauge.Value;
                                   
         app.BatteryModuleVoltageVGauge.Value = str2double(extractBetween(get_param('SystemModel/User Interface/Pack_Voltage','Value'),'"','"'));        
         app.BatteryModulueVoltageEditField.Value = app.BatteryModuleVoltageVGauge.Value;
         app.BatteryModulueVoltageEditField_2.Value = app.BatteryModuleVoltageVGauge.Value;
         
         app.BatteryModuleCurrentAGauge.Value = str2double(extractBetween(get_param('SystemModel/User Interface/Pack_Current','Value'),'"','"'));
         app.BatteryModulueCurrentEditField.Value = app.BatteryModuleCurrentAGauge.Value;
         app.BatteryModulueCurrentEditField_2.Value = app.BatteryModuleCurrentAGauge.Value;
         
         app.DischargemAGauge.Value = str2double(extractBetween(get_param('SystemModel/User Interface/Discharge','Value'),'"','"'));
         app.DischargeEditField.Value = app.DischargemAGauge.Value;
         
         app.ChargeGauge.Value = str2double(extractBetween(get_param('SystemModel/User Interface/Charge','Value'),'"','"'));
         app.ChargeEditField.Value = app.ChargeGauge.Value;        
         
         % Faults
         app.overCurrent.Value = str2double(get_param('SystemModel/User Interface/Lamp_overcurrent','Value'));
         app.highTemp.Value = str2double(get_param('SystemModel/User Interface/Lamp_HTemp','Value'));
         app.lowTemp.Value = str2double(get_param('SystemModel/User Interface/Lamp_LTemp','Value'));
         app.overVoltage.Value = str2double(get_param('SystemModel/User Interface/Lamp_overvoltage','Value'));
         app.underVoltage.Value = str2double(get_param('SystemModel/User Interface/Lamp_undervoltage','Value'));
         
         overCurrentValueChanged(app);
         highTempValueChanged(app);
         lowTempValueChanged(app);
         overVoltageValueChanged(app);
         underVoltageValueChanged(app);

         if app.alarmDetected == 0
             app.StatusMessagesTextArea.Value = '';
         end

         if app.alarmDetected == 0 && app.alarmDelay > 2
             app.alarmDetected = -1;
             app.alarmDelay = 0;
         end

         if app.alarmDetected == 0 && app.alarmDelay < 2
             app.alarmDelay = app.alarmDelay + 1;
         end

         if ~(app.overCurrent.Value || app.highTemp.Value || app.lowTemp.Value || app.overVoltage.Value || app.underVoltage.Value) && app.alarmDetected == 1
              app.alarmDetected = 0;             
         end
     end
 end        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Connect all Real-time components to the selected Target
            app.ConnectButton.TargetSource = app.TargetSelector;
            app.RebootButton.TargetSource = app.TargetSelector;
            app.UpdateButton.TargetSource = app.TargetSelector; 
            app.LoadButton.TargetSource = app.TargetSelector;
            app.StopTimeEditField.TargetSource = app.TargetSelector;
            app.SystemLog.TargetSource = app.TargetSelector;
            app.StatusBar.TargetSource = app.TargetSelector;

            % Set window size
             %screenSize = get(groot,'ScreenSize');
             %screenWidth = screenSize(3);
             %screenHeight = screenSize(4);
             %left = 204;%screenWidth*0.04;
             %bottom = 88;%screenHeight*0.06;
             %width = screenWidth*0.8135;
             %height = screenHeight*0.8472;            
             %drawnow;
             %app.BMSUIFigure.Position = [left bottom width height]; 

            app.myTimer = timer(...
                "ExecutionMode", "fixedRate", ...    % Run timer repeatedly
                "Period", 0.8, ...                   % Period is 800 milliseconds
                "BusyMode", "queue",...              % Queue timer callbacks when busy
                "TimerFcn", @app.TimerFcn);          % Specify callback function            

            proj = matlab.project.rootProject;
            if isempty(matlab.project.rootProject)
                try
                    open("BMS-Controller-HIL.prj");
                catch
                    app.StatusMessagesTextArea.Value = 'BMS Controller HIL Project should be in current MATLAB path';
                end
            elseif ~strcmp(proj.Name, "BMS-Controller-HIL")
                app.StatusMessagesTextArea.Value = 'Please close the other project. BMS Controller HIL Project should be in current MATLAB path';
            end
            
            try
                if get_param('SystemModel/Instrumentation/Simulation_Profile_Value', 'Value') ~= 0
                    switchConfig("MIL");
                end
            catch
                if matlab.project.rootProject().Name == "BMS-Controller-HIL"
                    SystemModel;
                    app.StatusMessagesTextArea.Value = 'System model is not loaded. Loading System model...';
                    switchConfig("MIL");
                else
                    app.StatusMessagesTextArea.Value = 'System model is not loaded. Load the model and restart the app.';
                end
            end

            %if strcmp(app.myTimer.Running, "off")
            %   start(app.myTimer);
            %end      

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;            
            app2model_current = value;
            assignin('base','app2model_current',value);
            app.ChargeDischargeCurrentAEditField.Value = app2model_current;
            set_param('SystemModel/Instrumentation/Current','Value','app2model_current');
        end

        % Selection changed function: SimulationModeButtonGroup
        function SimulationModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.SimulationModeButtonGroup.SelectedObject;                        

            if selectedButton.Text == "Model in the Loop"
                switchConfig("MIL");
            else 
                switchConfig("HIL");
            end

            if app.HardwareintheLoopButton.Value == 1
                app.DesktopSimStartButton.Enable = 'off';                                
                app.StatusMessagesTextArea.Value = 'Hardware in the loop (Real-time simulation) runs on Speedgoat target machine.';
            else
                app.DesktopSimStartButton.Enable = 'on';                
                app.StatusMessagesTextArea.Value = 'Model in the loop can run on the Host PC or Speedgoat target machine.';
            end
        end

        % Value changed function: TestScenarioDropDown
        function TestScenarioDropDownValueChanged(app, event)
            value = app.TestScenarioDropDown.Value;       
            
            assignin('base','app2model_stateStandby',SRE.Standby);
            assignin('base','app2model_stateDriving',SRE.Driving);
            assignin('base','app2model_stateCharging',SRE.Charging);
                        
            switch (num2str(value))
                case '0'
                    set_param('SystemModel/Instrumentation/State_Request','Value','app2model_stateStandby');                    
                case '1'
                    set_param('SystemModel/Instrumentation/State_Request','Value','app2model_stateDriving');                    
                case '2'
                    set_param('SystemModel/Instrumentation/State_Request','Value','app2model_stateCharging');                    
            end                      
            
        end

        % Value changed function: ChargeDischargeCurrentAEditField
        function ChargeDischargeCurrentmAEditFieldValueChanged(app, event)
            value = app.ChargeDischargeCurrentAEditField.Value;                         
            assignin('base','app2model_current',value);
            app.Slider.Value = value;            
            set_param('SystemModel/Instrumentation/Current','Value','app2model_current');
        end

        % Value changed function: AmbientTemperatureCEditField
        function AmbientTemperatureCEditFieldValueChanged(app, event)
            value = app.AmbientTemperatureCEditField.Value;
            assignin('base','app2model_ambientTemp',value);
            set_param('SystemModel/Instrumentation/Ambient_Temperature','Value','app2model_ambientTemp');                                             
        end

        % Value changed function: RealtimeSimStartButton
        function RealtimeSimStartButtonValueChanged(app, event)
            value = app.RealtimeSimStartButton.Value;
            mdl = 'SystemModel';
            app.RealtimeSimStartButton.Enable = 'off';
            
            tg = slrealtime(app.TargetSelector.TargetName);
            setDefaultTargetName(slrealtime.Targets(),app.TargetSelector.TargetName)
                        
            if  ~isConnected(tg)
                app.StatusMessagesTextArea.Value = 'Target machine not connected';
                app.RealtimeSimStartButton.Enable = 'on';
                app.RealtimeSimStartButton.Value = ~value;
                return
            end                    
            
            disableComponents(app);
            pause(2);
            
            switch value
                case 0
                    app.StatusMessagesTextArea.Value = 'Stopping Simulation...';
                    tg.stop;
                    pause(2);
                    status = get_param('SystemModel',"SimulationStatus");
                    if status == "stopped"           
                        app.StatusMessagesTextArea.Value = 'Real-time simulation has stopped.';
                        app.DesktopSimStartButton.Enable = 'on';
                    end

                    app.RealtimeSimStartButton.Text = "Start" + newline + newline + "(On Target)";
                    app.RealtimeSimStartButton.Icon = 'slrtRunIcon.png';
                    app.RealtimeSimStartButton.Enable = 'on';
                    
                    app.SimulationModeButtonGroup.Enable = 'on';

                    hold(app.UIAxes, 'off');

                case 1
                    app.SimulationModeButtonGroup.Enable = 'off';                    
                    app.StatusMessagesTextArea.Value = 'Building and compiling model...';
                    %pause(1);

                    % Build the Simulink model
                    slbuild(mdl);                                                  
                                        
                    app.StatusMessagesTextArea.Value = 'Loading model to target machine...';
                    pause(1);
                    try 
                        tg.load(mdl);
                    catch
                        app.StatusMessagesTextArea.Value = 'Error in loading the application to target. Please check System Log or MATLAB Command Window';
                    end
                    pause(1);                                        

                    app.StatusMessagesTextArea.Value = 'Starting simulation...';
                    
                    % Start simulation of real-time application                    
                    set_param('SystemModel','SimulationMode','external');
                    set_param('SystemModel',"SimulationCommand","connect");
                    set_param('SystemModel',"SimulationCommand","Start");            
                    pause(2);

                    status = get_param('SystemModel',"SimulationStatus");
                    if tg.isRunning || status == "external"           
                        app.StatusMessagesTextArea.Value = 'Real-time simulation is running on the target machine.';
                        app.DesktopSimStartButton.Enable = 'off';                    
                        
                        app.RealtimeSimStartButton.Text = 'Stop';
                        app.RealtimeSimStartButton.Icon = 'slrtStopIcon.png';
                        app.RealtimeSimStartButton.Enable = 'on';                                                
                    else
                        app.RealtimeSimStartButton.Enable = 'on';
                        app.RealtimeSimStartButton.Value = 0;
                        app.SimulationModeButtonGroup.Enable = 'on';
                        app.StatusMessagesTextArea.Value = 'Simulation did not start. Please try again!';
                    end
                    
                    if strcmp(app.myTimer.Running, "off")
                        start(app.myTimer);
                    end
            end      
            enableComponents(app);
        end

        % Value changed function: DesktopSimStartButton
        function DesktopSimStartButtonValueChanged(app, event)
            value = app.DesktopSimStartButton.Value;
            app.DesktopSimStartButton.Enable = "off";
            disableComponents(app);
            pause(1);

            switch value                
                case 0
                    set_param('SystemModel',"SimulationCommand","Stop");                        
                    pause(2);

                    status = get_param('SystemModel',"SimulationStatus");
                    if status == "stopped"           
                        app.StatusMessagesTextArea.Value = 'Simulation has stopped.';
                        app.RealtimeSimStartButton.Enable = 'on';
                        app.MILSimTime.Value = 'T=0';
                        app.MILSimTime.Visible = 'off';
                    end

                    app.DesktopSimStartButton.Text = "Start" + newline + newline + "(On Host)";
                    app.DesktopSimStartButton.Icon = 'slrtRunIcon.png';
                    app.DesktopSimStartButton.Enable = 'on';            

                    app.SimulationModeButtonGroup.Enable = 'on';

                    hold(app.UIAxes, 'off');
                
                case 1
                    app.StatusMessagesTextArea.Value = 'Compiling model...';
                    pause(1);
                    app.SimulationModeButtonGroup.Enable = 'off';
                    
                    try
                        set_param('SystemModel','SimulationMode','normal');           
                        set_param('SystemModel',"SimulationCommand","Start");
                    catch
                        app.StatusMessagesTextArea.Value = 'Model is not loaded. Load System model and restart the app.';
                    end

                    pause(2);
            
                    status = get_param('SystemModel',"SimulationStatus");
                    if status == "running"
                        app.StatusMessagesTextArea.Value = 'Model running in Host PC.';
                        app.RealtimeSimStartButton.Enable = 'off';
                        app.MILSimTime.Visible = 'on';
                    elseif status == "stopped"
                        app.StatusMessagesTextArea.Value = 'Error has occured in the Model. Cannot start simulation';
                        app.DesktopSimStartButton.Value = 0;
                        app.DesktopSimStartButton.Enable = "on";
                        app.SimulationModeButtonGroup.Enable = 'on';
                        exit;
                    else
                        app.StatusMessagesTextArea.Value = 'Simulation did not start. Please try again!';                        
                        app.DesktopSimStartButton.Value = 0;                        
                        app.DesktopSimStartButton.Enable = "on";
                        app.SimulationModeButtonGroup.Enable = 'on';
                        exit;                    
                    end
            
                    assignin('base','app2model_ambientTemp',app.AmbientTemperatureCEditField.Value);
                    set_param('SystemModel/Instrumentation/Ambient_Temperature','Value','app2model_ambientTemp');            
            
                    assignin('base','app2model_current',app.ChargeDischargeCurrentAEditField.Value);            
                    set_param('SystemModel/Instrumentation/Current','Value','app2model_current');

                    assignin('base','app2model_stateStandby',SRE.Standby);
                    set_param('SystemModel/Instrumentation/State_Request','Value','app2model_stateStandby');
                                    
                    app.DesktopSimStartButton.Text = 'Stop';
                    app.DesktopSimStartButton.Icon = 'slrtStopIcon.png';
                    app.DesktopSimStartButton.Enable = "on";
               
                    if strcmp(app.myTimer.Running, "off")
                        start(app.myTimer);
                    end                    
            end    
            enableComponents(app);
        end

        % Button pushed function: ConfigurationButton
        function ConfigurationButtonPushed(app, event)
            app.TabGroupMain.SelectedTab = app.ConfigurationTab;
        end

        % Value changed function: V_offButton_1
        function V_offButton_1ValueChanged(app, event)
            value = app.V_offButton_1.Value;
            switch value 
                case 1
                    app.V_offButton_1.Text = "on";
                    app.V_offButton_1.BackgroundColor = [0.39,0.83,0.07];
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell1_Voltage_Bypass_Value','Value',num2str(app.V_EditField_1.Value));
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell1_Voltage_Bypass','Value','1');                    
                case 0
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell1_Voltage_Bypass','Value','0');
                    app.V_offButton_1.Text = "off";
                    app.V_offButton_1.BackgroundColor = [0.96,0.96,0.96];
            end

        end

        % Value changed function: V_offButton_2
        function V_offButton_2ValueChanged(app, event)
            value = app.V_offButton_2.Value;
            switch value 
                case 1
                    app.V_offButton_2.Text = "on";
                    app.V_offButton_2.BackgroundColor = [0.39,0.83,0.07];
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell2_Voltage_Bypass_Value','Value',num2str(app.V_EditField_2.Value));
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell2_Voltage_Bypass','Value','1');                    
                case 0
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell2_Voltage_Bypass','Value','0');
                    app.V_offButton_2.Text = "off";
                    app.V_offButton_2.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Value changed function: V_offButton_3
        function V_offButton_3ValueChanged(app, event)
            value = app.V_offButton_3.Value;
            switch value 
                case 1
                    app.V_offButton_3.Text = "on";
                    app.V_offButton_3.BackgroundColor = [0.39,0.83,0.07];
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell3_Voltage_Bypass_Value','Value',num2str(app.V_EditField_3.Value));
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell3_Voltage_Bypass','Value','1');                    
                case 0
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell3_Voltage_Bypass','Value','0');
                    app.V_offButton_3.Text = "off";
                    app.V_offButton_3.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Value changed function: V_offButton_4
        function V_offButton_4ValueChanged(app, event)
            value = app.V_offButton_4.Value;
            switch value 
                case 1
                    app.V_offButton_4.Text = "on";
                    app.V_offButton_4.BackgroundColor = [0.39,0.83,0.07];
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell4_Voltage_Bypass_Value','Value',num2str(app.V_EditField_4.Value));
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell4_Voltage_Bypass','Value','1');                    
                case 0
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell4_Voltage_Bypass','Value','0');
                    app.V_offButton_4.Text = "off";
                    app.V_offButton_4.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Value changed function: V_offButton_5
        function V_offButton_5ValueChanged(app, event)
            value = app.V_offButton_5.Value;
            switch value 
                case 1
                    app.V_offButton_5.Text = "on";                    
                    app.V_offButton_5.BackgroundColor = [0.39,0.83,0.07];
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell5_Voltage_Bypass_Value','Value',num2str(app.V_EditField_5.Value));
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell5_Voltage_Bypass','Value','1');                    
                case 0
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell5_Voltage_Bypass','Value','0');
                    app.V_offButton_5.Text = "off";
                    app.V_offButton_5.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Value changed function: V_offButton_6
        function V_offButton_6ValueChanged(app, event)
            value = app.V_offButton_6.Value;
            switch value 
                case 1
                    app.V_offButton_6.Text = "on";
                    app.V_offButton_6.BackgroundColor = [0.39,0.83,0.07];
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell6_Voltage_Bypass_Value','Value',num2str(app.V_EditField_6.Value));
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell6_Voltage_Bypass','Value','1');                    
                case 0
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell6_Voltage_Bypass','Value','0');
                    app.V_offButton_6.Text = "off";
                    app.V_offButton_6.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Value changed function: V_offButton_7
        function V_offButton_7ValueChanged(app, event)
            value = app.V_offButton_7.Value;
            switch value 
                case 1
                    app.V_offButton_7.Text = "on";
                    app.V_offButton_7.BackgroundColor = [0.39,0.83,0.07];
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell7_Voltage_Bypass_Value','Value',num2str(app.V_EditField_7.Value));
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell7_Voltage_Bypass','Value','1');                    
                case 0
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell7_Voltage_Bypass','Value','0');
                    app.V_offButton_7.Text = "off";
                    app.V_offButton_7.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Value changed function: V_offButton_8
        function V_offButton_8ValueChanged(app, event)
            value = app.V_offButton_8.Value;
            switch value 
                case 1
                    app.V_offButton_8.Text = "on";
                    app.V_offButton_8.BackgroundColor = [0.39,0.83,0.07];
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell8_Voltage_Bypass_Value','Value',num2str(app.V_EditField_8.Value));
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell8_Voltage_Bypass','Value','1');                    
                case 0
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell8_Voltage_Bypass','Value','0');
                    app.V_offButton_8.Text = "off";
                    app.V_offButton_8.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Value changed function: V_offButton_9
        function V_offButton_9ValueChanged(app, event)
            value = app.V_offButton_9.Value;
            switch value 
                case 1
                    app.V_offButton_9.Text = "on";
                    app.V_offButton_9.BackgroundColor = [0.39,0.83,0.07];
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell9_Voltage_Bypass_Value','Value',num2str(app.V_EditField_9.Value));
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell9_Voltage_Bypass','Value','1');                    
                case 0
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell9_Voltage_Bypass','Value','0');
                    app.V_offButton_9.Text = "off";
                    app.V_offButton_9.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Value changed function: V_offButton_10
        function V_offButton_10ValueChanged(app, event)
            value = app.V_offButton_10.Value;
            switch value 
                case 1
                    app.V_offButton_10.Text = "on";
                    app.V_offButton_10.BackgroundColor = [0.39,0.83,0.07];
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell10_Voltage_Bypass_Value','Value',num2str(app.V_EditField_10.Value));
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell10_Voltage_Bypass','Value','1');                    
                case 0
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell10_Voltage_Bypass','Value','0');
                    app.V_offButton_10.Text = "off";
                    app.V_offButton_10.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Value changed function: V_offButton_11
        function V_offButton_11ValueChanged(app, event)
            value = app.V_offButton_11.Value;
            switch value 
                case 1
                    app.V_offButton_11.Text = "on";
                    app.V_offButton_11.BackgroundColor = [0.39,0.83,0.07];
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell11_Voltage_Bypass_Value','Value',num2str(app.V_EditField_11.Value));
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell11_Voltage_Bypass','Value','1');                    
                case 0
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell11_Voltage_Bypass','Value','0');
                    app.V_offButton_11.Text = "off";
                    app.V_offButton_11.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Value changed function: V_offButton_12
        function V_offButton_12ValueChanged(app, event)
            value = app.V_offButton_12.Value;
            switch value 
                case 1
                    app.V_offButton_12.Text = "on";
                    app.V_offButton_12.BackgroundColor = [0.39,0.83,0.07];
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell12_Voltage_Bypass_Value','Value',num2str(app.V_EditField_12.Value));
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell12_Voltage_Bypass','Value','1');                    
                case 0
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell12_Voltage_Bypass','Value','0');
                    app.V_offButton_12.Text = "off";
                    app.V_offButton_12.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Value changed function: V_EditField_1
        function V_EditField_1_1ValueChanged(app, event)
            value = app.V_EditField_1.Value;
            if app.V_offButton_1.Value == 1
                set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell1_Voltage_Bypass_Value','Value',num2str(value));
            end
        end

        % Value changed function: V_EditField_2
        function V_EditField_2ValueChanged(app, event)
            value = app.V_EditField_2.Value;
            if app.V_offButton_2.Value == 1
                set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell2_Voltage_Bypass_Value','Value',num2str(value));
            end
        end

        % Value changed function: V_EditField_3
        function V_EditField_3ValueChanged(app, event)
            value = app.V_EditField_3.Value;
            if app.V_offButton_3.Value == 1
                set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell3_Voltage_Bypass_Value','Value',num2str(value));
            end
        end

        % Value changed function: V_EditField_4
        function V_EditField_4ValueChanged(app, event)
            value = app.V_EditField_4.Value;
            if app.V_offButton_4.Value == 1
                set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell4_Voltage_Bypass_Value','Value',num2str(value));
            end
        end

        % Value changed function: V_EditField_5
        function V_EditField_5ValueChanged(app, event)
            value = app.V_EditField_5.Value;
            if app.V_offButton_5.Value == 1
                set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell5_Voltage_Bypass_Value','Value',num2str(value));
            end
        end

        % Value changed function: V_EditField_6
        function V_EditField_6ValueChanged(app, event)
            value = app.V_EditField_6.Value;
            if app.V_offButton_6.Value == 1
                set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell6_Voltage_Bypass_Value','Value',num2str(value));
            end
        end

        % Value changed function: V_EditField_7
        function V_EditField_7ValueChanged(app, event)
            value = app.V_EditField_7.Value;
            if app.V_offButton_7.Value == 1
                set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell7_Voltage_Bypass_Value','Value',num2str(value));
            end
        end

        % Value changed function: V_EditField_8
        function V_EditField_8ValueChanged(app, event)
            value = app.V_EditField_8.Value;
            if app.V_offButton_8.Value == 1
                set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell8_Voltage_Bypass_Value','Value',num2str(value));
            end
        end

        % Value changed function: V_EditField_9
        function V_EditField_9ValueChanged(app, event)
            value = app.V_EditField_9.Value;
            if app.V_offButton_9.Value == 1
                set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell9_Voltage_Bypass_Value','Value',num2str(value));
            end
        end

        % Value changed function: V_EditField_10
        function V_EditField_10ValueChanged(app, event)
            value = app.V_EditField_10.Value;
            if app.V_offButton_10.Value == 1
                set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell10_Voltage_Bypass_Value','Value',num2str(value));
            end
        end

        % Value changed function: V_EditField_11
        function V_EditField_11ValueChanged(app, event)
            value = app.V_EditField_11.Value;
            if app.V_offButton_11.Value == 1
                set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell11_Voltage_Bypass_Value','Value',num2str(value));
            end
        end

        % Value changed function: V_EditField_12
        function V_EditField_12ValueChanged(app, event)
            value = app.V_EditField_12.Value;
            if app.V_offButton_12.Value == 1
                set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell12_Voltage_Bypass_Value','Value',num2str(value));
            end
        end

        % Value changed function: T_EditField_1
        function T_EditField_1ValueChanged(app, event)
            value = app.T_EditField_1.Value;
            if app.T_offButton_1.Value == 1
                set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell1_Temperature_Bypass_Value','Value',num2str(value));
            end
        end

        % Value changed function: T_EditField_2
        function T_EditField_2ValueChanged(app, event)
            value = app.T_EditField_2.Value;
            if app.T_offButton_2.Value == 1
                set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell2_Temperature_Bypass_Value','Value',num2str(value));
            end
        end

        % Value changed function: T_EditField_3
        function T_EditField_3ValueChanged(app, event)
            value = app.T_EditField_3.Value;
            if app.T_offButton_3.Value == 1
                set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell3_Temperature_Bypass_Value','Value',num2str(value));
            end
        end

        % Value changed function: T_EditField_4
        function T_EditField_4ValueChanged(app, event)
            value = app.T_EditField_4.Value;
            if app.T_offButton_4.Value == 1
                set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell4_Temperature_Bypass_Value','Value',num2str(value));
            end
        end

        % Value changed function: T_EditField_5
        function T_EditField_5ValueChanged(app, event)
            value = app.T_EditField_5.Value;
            if app.T_offButton_5.Value == 1
                set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell5_Temperature_Bypass_Value','Value',num2str(value));
            end
        end

        % Value changed function: T_EditField_6
        function T_EditField_6ValueChanged(app, event)
            value = app.T_EditField_6.Value;
            if app.T_offButton_6.Value == 1
                set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell6_Temperature_Bypass_Value','Value',num2str(value));
            end
        end

        % Value changed function: T_EditField_7
        function T_EditField_7ValueChanged(app, event)
            value = app.T_EditField_7.Value;
            if app.T_offButton_7.Value == 1
                set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell7_Temperature_Bypass_Value','Value',num2str(value));
            end
        end

        % Value changed function: T_EditField_8
        function T_EditField_8ValueChanged(app, event)
            value = app.T_EditField_8.Value;
            if app.T_offButton_8.Value == 1
                set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell8_Temperature_Bypass_Value','Value',num2str(value));
            end
        end

        % Value changed function: T_EditField_9
        function T_EditField_9ValueChanged(app, event)
            value = app.T_EditField_9.Value;
            if app.T_offButton_9.Value == 1
                set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell9_Temperature_Bypass_Value','Value',num2str(value));
            end
        end

        % Value changed function: T_EditField_10
        function T_EditField_10ValueChanged(app, event)
            value = app.T_EditField_10.Value;
            if app.T_offButton_10.Value == 1
                set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell10_Temperature_Bypass_Value','Value',num2str(value));
            end
        end

        % Value changed function: T_EditField_11
        function T_EditField_11ValueChanged(app, event)
            value = app.T_EditField_11.Value;
            if app.T_offButton_11.Value == 1
                set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell11_Temperature_Bypass_Value','Value',num2str(value));
            end
        end

        % Value changed function: T_EditField_12
        function T_EditField_12ValueChanged(app, event)
            value = app.T_EditField_12.Value;
            if app.T_offButton_12.Value == 1
                set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell12_Temperature_Bypass_Value','Value',num2str(value));
            end
        end

        % Value changed function: T_offButton_1
        function T_offButton_1ValueChanged(app, event)
            value = app.T_offButton_1.Value;
            switch value 
                case 1
                    app.T_offButton_1.Text = "on";
                    app.T_offButton_1.BackgroundColor = [0.39,0.83,0.07];
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell1_Temperature_Bypass_Value','Value',num2str(app.T_EditField_1.Value));
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell1_Temperature_Bypass','Value','1');                    
                case 0
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell1_Temperature_Bypass','Value','0');
                    app.T_offButton_1.Text = "off";
                    app.T_offButton_1.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Value changed function: T_offButton_2
        function T_offButton_2ValueChanged(app, event)
            value = app.T_offButton_2.Value;
            switch value 
                case 1
                    app.T_offButton_2.Text = "on";
                    app.T_offButton_2.BackgroundColor = [0.39,0.83,0.07];
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell2_Temperature_Bypass_Value','Value',num2str(app.T_EditField_2.Value));
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell2_Temperature_Bypass','Value','1');                    
                case 0
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell2_Temperature_Bypass','Value','0');
                    app.T_offButton_2.Text = "off";
                    app.T_offButton_2.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Value changed function: T_offButton_3
        function T_offButton_3ValueChanged(app, event)
            value = app.T_offButton_3.Value;
            switch value 
                case 1
                    app.T_offButton_3.Text = "on";
                    app.T_offButton_3.BackgroundColor = [0.39,0.83,0.07];
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell3_Temperature_Bypass_Value','Value',num2str(app.T_EditField_3.Value));
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell3_Temperature_Bypass','Value','1');                    
                case 0
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell3_Temperature_Bypass','Value','0');
                    app.T_offButton_3.Text = "off";
                    app.T_offButton_3.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Value changed function: T_offButton_4
        function T_offButton_4ValueChanged(app, event)
            value = app.T_offButton_4.Value;
            switch value 
                case 1
                    app.T_offButton_4.Text = "on";
                    app.T_offButton_4.BackgroundColor = [0.39,0.83,0.07];
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell4_Temperature_Bypass_Value','Value',num2str(app.T_EditField_4.Value));
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell4_Temperature_Bypass','Value','1');                    
                case 0
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell4_Temperature_Bypass','Value','0');
                    app.T_offButton_4.Text = "off";
                    app.T_offButton_4.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Value changed function: T_offButton_5
        function T_offButton_5ValueChanged(app, event)
            value = app.T_offButton_5.Value;
            switch value 
                case 1
                    app.T_offButton_5.Text = "on";
                    app.T_offButton_5.BackgroundColor = [0.39,0.83,0.07];
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell5_Temperature_Bypass_Value','Value',num2str(app.T_EditField_5.Value));
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell5_Temperature_Bypass','Value','1');                    
                case 0
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell5_Temperature_Bypass','Value','0');
                    app.T_offButton_5.Text = "off";
                    app.T_offButton_5.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Value changed function: T_offButton_6
        function T_offButton_6ValueChanged(app, event)
            value = app.T_offButton_6.Value;
            switch value 
                case 1
                    app.T_offButton_6.Text = "on";
                    app.T_offButton_6.BackgroundColor = [0.39,0.83,0.07];
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell6_Temperature_Bypass_Value','Value',num2str(app.T_EditField_6.Value));
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell6_Temperature_Bypass','Value','1');                    
                case 0
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell6_Temperature_Bypass','Value','0');
                    app.T_offButton_6.Text = "off";
                    app.T_offButton_6.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Value changed function: T_offButton_7
        function T_offButton_7ValueChanged(app, event)
            value = app.T_offButton_7.Value;
            switch value 
                case 1
                    app.T_offButton_7.Text = "on";
                    app.T_offButton_7.BackgroundColor = [0.39,0.83,0.07];
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell7_Temperature_Bypass_Value','Value',num2str(app.T_EditField_7.Value));
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell7_Temperature_Bypass','Value','1');                    
                case 0
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell7_Temperature_Bypass','Value','0');
                    app.T_offButton_7.Text = "off";
                    app.T_offButton_7.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Value changed function: T_offButton_8
        function T_offButton_8ValueChanged(app, event)
            value = app.T_offButton_8.Value;
            switch value 
                case 1
                    app.T_offButton_8.Text = "on";
                    app.T_offButton_8.BackgroundColor = [0.39,0.83,0.07];
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell8_Temperature_Bypass_Value','Value',num2str(app.T_EditField_8.Value));
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell8_Temperature_Bypass','Value','1');                    
                case 0
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell8_Temperature_Bypass','Value','0');
                    app.T_offButton_8.Text = "off";
                    app.T_offButton_8.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Value changed function: T_offButton_9
        function T_offButton_9ValueChanged(app, event)
            value = app.T_offButton_9.Value;
            switch value 
                case 1
                    app.T_offButton_9.Text = "on";
                    app.T_offButton_9.BackgroundColor = [0.39,0.83,0.07];
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell9_Temperature_Bypass_Value','Value',num2str(app.T_EditField_9.Value));
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell9_Temperature_Bypass','Value','1');                    
                case 0
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell9_Temperature_Bypass','Value','0');
                    app.T_offButton_9.Text = "off";
                    app.T_offButton_9.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Value changed function: T_offButton_10
        function T_offButton_10ValueChanged(app, event)
            value = app.T_offButton_10.Value;
            switch value 
                case 1
                    app.T_offButton_10.Text = "on";
                    app.T_offButton_10.BackgroundColor = [0.39,0.83,0.07];
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell10_Temperature_Bypass_Value','Value',num2str(app.T_EditField_10.Value));
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell10_Temperature_Bypass','Value','1');                    
                case 0
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell10_Temperature_Bypass','Value','0');
                    app.T_offButton_10.Text = "off";
                    app.T_offButton_10.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Value changed function: T_offButton_11
        function T_offButton_11ValueChanged(app, event)
            value = app.T_offButton_11.Value;
            switch value 
                case 1
                    app.T_offButton_11.Text = "on";
                    app.T_offButton_11.BackgroundColor = [0.39,0.83,0.07];
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell11_Temperature_Bypass_Value','Value',num2str(app.T_EditField_11.Value));
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell11_Temperature_Bypass','Value','1');                    
                case 0
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell11_Temperature_Bypass','Value','0');
                    app.T_offButton_11.Text = "off";
                    app.T_offButton_11.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Value changed function: T_offButton_12
        function T_offButton_12ValueChanged(app, event)
            value = app.T_offButton_12.Value;
            switch value 
                case 1
                    app.T_offButton_12.Text = "on";
                    app.T_offButton_12.BackgroundColor = [0.39,0.83,0.07];
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell12_Temperature_Bypass_Value','Value',num2str(app.T_EditField_12.Value));
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell12_Temperature_Bypass','Value','1');                    
                case 0
                    set_param('SystemModel/Battery/Bypass/Bypass Values/12-Cell/Cell12_Temperature_Bypass','Value','0');
                    app.T_offButton_12.Text = "off";
                    app.T_offButton_12.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Value changed function: highTemp
        function highTempValueChanged(app, event)
            value = app.highTemp.Value;            
            if value
              app.CellhightemperatureLamp.Color = [1.00,0.00,0.00];
              app.StatusMessagesTextArea.Value = 'ALARM!! High cell temperature detected.';
              app.alarmDetected = 1;
            else
              app.CellhightemperatureLamp.Color = [1.00,1.00,1.00];             
            end               
        end

        % Value changed function: lowTemp
        function lowTempValueChanged(app, event)
            value = app.lowTemp.Value;
            if value
                app.CelllowtemperatureLamp.Color = [1.00,0.00,0.00];
                app.StatusMessagesTextArea.Value = 'ALARM!! Low cell temperature detected.';
                app.alarmDetected = 1;
            else
                app.CelllowtemperatureLamp.Color = [1.00,1.00,1.00];                
            end
        end

        % Value changed function: overVoltage
        function overVoltageValueChanged(app, event)
            value = app.overVoltage.Value;            
            if value
                app.CellovervoltageLamp.Color = [1.00,0.00,0.00];
                app.StatusMessagesTextArea.Value = 'ALARM!! Cell over voltage detected.';
                app.alarmDetected = 1;
            else
                app.CellovervoltageLamp.Color = [1.00,1.00,1.00];                
            end            
        end

        % Value changed function: underVoltage
        function underVoltageValueChanged(app, event)
            value = app.underVoltage.Value;
            if value
                app.CellundervoltageLamp.Color = [1.00,0.00,0.00];
                app.StatusMessagesTextArea.Value = 'ALARM!! Cell under voltage detected.';
                app.alarmDetected = 1;
            else
                app.CellundervoltageLamp.Color = [1.00,1.00,1.00];                
            end
        end

        % Value changed function: overCurrent
        function overCurrentValueChanged(app, event)
            value = app.overCurrent.Value;
            if value
                app.ModuleovercurrentLamp.Color = [1.00,0.00,0.00];
                app.StatusMessagesTextArea.Value = 'ALARM!! Module over current detected.';
                app.alarmDetected = 1;
            else
                app.ModuleovercurrentLamp.Color = [1.00,1.00,1.00];                
            end         
        end

        % Value changed function: SC_offButton_1
        function SC_offButton_1ValueChanged(app, event)
            value = app.SC_offButton_1.Value;
            switch value 
                case 1
                    app.SC_offButton_1.Text = "on";
                    app.SC_offButton_1.BackgroundColor = [0.39,0.83,0.07];                    
                case 0                    
                    app.SC_offButton_1.Text = "off";
                    app.SC_offButton_1.BackgroundColor = [0.96,0.96,0.96];
            end

            Short_circuit = ['[',num2str(app.SC_offButton_12.Value),',',num2str(app.SC_offButton_11.Value),',',num2str(app.SC_offButton_10.Value),',',num2str(app.SC_offButton_9.Value),',',num2str(app.SC_offButton_8.Value),',',num2str(app.SC_offButton_7.Value),',',num2str(app.SC_offButton_6.Value),',',num2str(app.SC_offButton_5.Value),',',num2str(app.SC_offButton_4.Value),',',num2str(app.SC_offButton_3.Value),',',num2str(app.SC_offButton_2.Value),',',num2str(app.SC_offButton_1.Value),']'];
            set_param('SystemModel/Instrumentation/Instrumented Signals/12-Cell/Short_Circuits_Value','Value',Short_circuit);
        end

        % Value changed function: SC_offButton_2
        function SC_offButton_2ValueChanged(app, event)
            value = app.SC_offButton_2.Value;
            switch value 
                case 1
                    app.SC_offButton_2.Text = "on";
                    app.SC_offButton_2.BackgroundColor = [0.39,0.83,0.07];                    
                case 0                    
                    app.SC_offButton_2.Text = "off";
                    app.SC_offButton_2.BackgroundColor = [0.96,0.96,0.96];
            end

            Short_circuit = ['[',num2str(app.SC_offButton_12.Value),',',num2str(app.SC_offButton_11.Value),',',num2str(app.SC_offButton_10.Value),',',num2str(app.SC_offButton_9.Value),',',num2str(app.SC_offButton_8.Value),',',num2str(app.SC_offButton_7.Value),',',num2str(app.SC_offButton_6.Value),',',num2str(app.SC_offButton_5.Value),',',num2str(app.SC_offButton_4.Value),',',num2str(app.SC_offButton_3.Value),',',num2str(app.SC_offButton_2.Value),',',num2str(app.SC_offButton_1.Value),']'];
            set_param('SystemModel/Instrumentation/Instrumented Signals/12-Cell/Short_Circuits_Value','Value',Short_circuit);
        end

        % Value changed function: SC_offButton_3
        function SC_offButton_3ValueChanged(app, event)
            value = app.SC_offButton_3.Value;
            switch value 
                case 1
                    app.SC_offButton_3.Text = "on";
                    app.SC_offButton_3.BackgroundColor = [0.39,0.83,0.07];                    
                case 0                    
                    app.SC_offButton_3.Text = "off";
                    app.SC_offButton_3.BackgroundColor = [0.96,0.96,0.96];
            end

            Short_circuit = ['[',num2str(app.SC_offButton_12.Value),',',num2str(app.SC_offButton_11.Value),',',num2str(app.SC_offButton_10.Value),',',num2str(app.SC_offButton_9.Value),',',num2str(app.SC_offButton_8.Value),',',num2str(app.SC_offButton_7.Value),',',num2str(app.SC_offButton_6.Value),',',num2str(app.SC_offButton_5.Value),',',num2str(app.SC_offButton_4.Value),',',num2str(app.SC_offButton_3.Value),',',num2str(app.SC_offButton_2.Value),',',num2str(app.SC_offButton_1.Value),']'];
            set_param('SystemModel/Instrumentation/Instrumented Signals/12-Cell/Short_Circuits_Value','Value',Short_circuit);
        end

        % Value changed function: SC_offButton_4
        function SC_offButton_4ValueChanged(app, event)
            value = app.SC_offButton_4.Value;
            switch value 
                case 1
                    app.SC_offButton_4.Text = "on";
                    app.SC_offButton_4.BackgroundColor = [0.39,0.83,0.07];                    
                case 0                    
                    app.SC_offButton_4.Text = "off";
                    app.SC_offButton_4.BackgroundColor = [0.96,0.96,0.96];
            end

            Short_circuit = ['[',num2str(app.SC_offButton_12.Value),',',num2str(app.SC_offButton_11.Value),',',num2str(app.SC_offButton_10.Value),',',num2str(app.SC_offButton_9.Value),',',num2str(app.SC_offButton_8.Value),',',num2str(app.SC_offButton_7.Value),',',num2str(app.SC_offButton_6.Value),',',num2str(app.SC_offButton_5.Value),',',num2str(app.SC_offButton_4.Value),',',num2str(app.SC_offButton_3.Value),',',num2str(app.SC_offButton_2.Value),',',num2str(app.SC_offButton_1.Value),']'];
            set_param('SystemModel/Instrumentation/Instrumented Signals/12-Cell/Short_Circuits_Value','Value',Short_circuit);
        end

        % Value changed function: SC_offButton_5
        function SC_offButton_5ValueChanged(app, event)
            value = app.SC_offButton_5.Value;
            switch value 
                case 1
                    app.SC_offButton_5.Text = "on";
                    app.SC_offButton_5.BackgroundColor = [0.39,0.83,0.07];                    
                case 0                    
                    app.SC_offButton_5.Text = "off";
                    app.SC_offButton_5.BackgroundColor = [0.96,0.96,0.96];
            end

            Short_circuit = ['[',num2str(app.SC_offButton_12.Value),',',num2str(app.SC_offButton_11.Value),',',num2str(app.SC_offButton_10.Value),',',num2str(app.SC_offButton_9.Value),',',num2str(app.SC_offButton_8.Value),',',num2str(app.SC_offButton_7.Value),',',num2str(app.SC_offButton_6.Value),',',num2str(app.SC_offButton_5.Value),',',num2str(app.SC_offButton_4.Value),',',num2str(app.SC_offButton_3.Value),',',num2str(app.SC_offButton_2.Value),',',num2str(app.SC_offButton_1.Value),']'];
            set_param('SystemModel/Instrumentation/Instrumented Signals/12-Cell/Short_Circuits_Value','Value',Short_circuit);
        end

        % Value changed function: SC_offButton_6
        function SC_offButton_6ValueChanged(app, event)
            value = app.SC_offButton_6.Value;
            switch value 
                case 1
                    app.SC_offButton_6.Text = "on";
                    app.SC_offButton_6.BackgroundColor = [0.39,0.83,0.07];                    
                case 0                    
                    app.SC_offButton_6.Text = "off";
                    app.SC_offButton_6.BackgroundColor = [0.96,0.96,0.96];
            end

            Short_circuit = ['[',num2str(app.SC_offButton_12.Value),',',num2str(app.SC_offButton_11.Value),',',num2str(app.SC_offButton_10.Value),',',num2str(app.SC_offButton_9.Value),',',num2str(app.SC_offButton_8.Value),',',num2str(app.SC_offButton_7.Value),',',num2str(app.SC_offButton_6.Value),',',num2str(app.SC_offButton_5.Value),',',num2str(app.SC_offButton_4.Value),',',num2str(app.SC_offButton_3.Value),',',num2str(app.SC_offButton_2.Value),',',num2str(app.SC_offButton_1.Value),']'];
            set_param('SystemModel/Instrumentation/Instrumented Signals/12-Cell/Short_Circuits_Value','Value',Short_circuit);
        end

        % Value changed function: SC_offButton_8
        function SC_offButton_8ValueChanged(app, event)
            value = app.SC_offButton_8.Value;
            switch value 
                case 1
                    app.SC_offButton_8.Text = "on";
                    app.SC_offButton_8.BackgroundColor = [0.39,0.83,0.07];                    
                case 0                    
                    app.SC_offButton_8.Text = "off";
                    app.SC_offButton_8.BackgroundColor = [0.96,0.96,0.96];
            end

            Short_circuit = ['[',num2str(app.SC_offButton_12.Value),',',num2str(app.SC_offButton_11.Value),',',num2str(app.SC_offButton_10.Value),',',num2str(app.SC_offButton_9.Value),',',num2str(app.SC_offButton_8.Value),',',num2str(app.SC_offButton_7.Value),',',num2str(app.SC_offButton_6.Value),',',num2str(app.SC_offButton_5.Value),',',num2str(app.SC_offButton_4.Value),',',num2str(app.SC_offButton_3.Value),',',num2str(app.SC_offButton_2.Value),',',num2str(app.SC_offButton_1.Value),']'];
            set_param('SystemModel/Instrumentation/Instrumented Signals/12-Cell/Short_Circuits_Value','Value',Short_circuit);
        end

        % Value changed function: SC_offButton_9
        function SC_offButton_9ValueChanged(app, event)
            value = app.SC_offButton_9.Value;
            switch value 
                case 1
                    app.SC_offButton_9.Text = "on";
                    app.SC_offButton_9.BackgroundColor = [0.39,0.83,0.07];                    
                case 0                    
                    app.SC_offButton_9.Text = "off";
                    app.SC_offButton_9.BackgroundColor = [0.96,0.96,0.96];
            end

            Short_circuit = ['[',num2str(app.SC_offButton_12.Value),',',num2str(app.SC_offButton_11.Value),',',num2str(app.SC_offButton_10.Value),',',num2str(app.SC_offButton_9.Value),',',num2str(app.SC_offButton_8.Value),',',num2str(app.SC_offButton_7.Value),',',num2str(app.SC_offButton_6.Value),',',num2str(app.SC_offButton_5.Value),',',num2str(app.SC_offButton_4.Value),',',num2str(app.SC_offButton_3.Value),',',num2str(app.SC_offButton_2.Value),',',num2str(app.SC_offButton_1.Value),']'];
            set_param('SystemModel/Instrumentation/Instrumented Signals/12-Cell/Short_Circuits_Value','Value',Short_circuit);
        end

        % Value changed function: SC_offButton_10
        function SC_offButton_10ValueChanged(app, event)
            value = app.SC_offButton_10.Value;
            switch value 
                case 1
                    app.SC_offButton_10.Text = "on";
                    app.SC_offButton_10.BackgroundColor = [0.39,0.83,0.07];                    
                case 0                    
                    app.SC_offButton_10.Text = "off";
                    app.SC_offButton_10.BackgroundColor = [0.96,0.96,0.96];
            end

            Short_circuit = ['[',num2str(app.SC_offButton_12.Value),',',num2str(app.SC_offButton_11.Value),',',num2str(app.SC_offButton_10.Value),',',num2str(app.SC_offButton_9.Value),',',num2str(app.SC_offButton_8.Value),',',num2str(app.SC_offButton_7.Value),',',num2str(app.SC_offButton_6.Value),',',num2str(app.SC_offButton_5.Value),',',num2str(app.SC_offButton_4.Value),',',num2str(app.SC_offButton_3.Value),',',num2str(app.SC_offButton_2.Value),',',num2str(app.SC_offButton_1.Value),']'];
            set_param('SystemModel/Instrumentation/Instrumented Signals/12-Cell/Short_Circuits_Value','Value',Short_circuit);
        end

        % Value changed function: SC_offButton_11
        function SC_offButton_11ValueChanged(app, event)
            value = app.SC_offButton_11.Value;
            switch value 
                case 1
                    app.SC_offButton_11.Text = "on";
                    app.SC_offButton_11.BackgroundColor = [0.39,0.83,0.07];                    
                case 0                    
                    app.SC_offButton_11.Text = "off";
                    app.SC_offButton_11.BackgroundColor = [0.96,0.96,0.96];
            end

            Short_circuit = ['[',num2str(app.SC_offButton_12.Value),',',num2str(app.SC_offButton_11.Value),',',num2str(app.SC_offButton_10.Value),',',num2str(app.SC_offButton_9.Value),',',num2str(app.SC_offButton_8.Value),',',num2str(app.SC_offButton_7.Value),',',num2str(app.SC_offButton_6.Value),',',num2str(app.SC_offButton_5.Value),',',num2str(app.SC_offButton_4.Value),',',num2str(app.SC_offButton_3.Value),',',num2str(app.SC_offButton_2.Value),',',num2str(app.SC_offButton_1.Value),']'];
            set_param('SystemModel/Instrumentation/Instrumented Signals/12-Cell/Short_Circuits_Value','Value',Short_circuit);
        end

        % Value changed function: SC_offButton_12
        function SC_offButton_12ValueChanged(app, event)
            value = app.SC_offButton_12.Value;
            switch value 
                case 1
                    app.SC_offButton_12.Text = "on";
                    app.SC_offButton_12.BackgroundColor = [0.39,0.83,0.07];                    
                case 0                    
                    app.SC_offButton_12.Text = "off";
                    app.SC_offButton_12.BackgroundColor = [0.96,0.96,0.96];
            end

            Short_circuit = ['[',num2str(app.SC_offButton_12.Value),',',num2str(app.SC_offButton_11.Value),',',num2str(app.SC_offButton_10.Value),',',num2str(app.SC_offButton_9.Value),',',num2str(app.SC_offButton_8.Value),',',num2str(app.SC_offButton_7.Value),',',num2str(app.SC_offButton_6.Value),',',num2str(app.SC_offButton_5.Value),',',num2str(app.SC_offButton_4.Value),',',num2str(app.SC_offButton_3.Value),',',num2str(app.SC_offButton_2.Value),',',num2str(app.SC_offButton_1.Value),']'];
            set_param('SystemModel/Instrumentation/Instrumented Signals/12-Cell/Short_Circuits_Value','Value',Short_circuit);
        end

        % Value changed function: SC_offButton_7
        function SC_offButton_7ValueChanged(app, event)
            value = app.SC_offButton_7.Value;
            switch value 
                case 1
                    app.SC_offButton_7.Text = "on";
                    app.SC_offButton_7.BackgroundColor = [0.39,0.83,0.07];                    
                case 0                    
                    app.SC_offButton_7.Text = "off";
                    app.SC_offButton_7.BackgroundColor = [0.96,0.96,0.96];
            end

            Short_circuit = ['[',num2str(app.SC_offButton_12.Value),',',num2str(app.SC_offButton_11.Value),',',num2str(app.SC_offButton_10.Value),',',num2str(app.SC_offButton_9.Value),',',num2str(app.SC_offButton_8.Value),',',num2str(app.SC_offButton_7.Value),',',num2str(app.SC_offButton_6.Value),',',num2str(app.SC_offButton_5.Value),',',num2str(app.SC_offButton_4.Value),',',num2str(app.SC_offButton_3.Value),',',num2str(app.SC_offButton_2.Value),',',num2str(app.SC_offButton_1.Value),']'];
            set_param('SystemModel/Instrumentation/Instrumented Signals/12-Cell/Short_Circuits_Value','Value',Short_circuit);
        end

        % Value changed function: OC_offButton_1
        function OC_offButton_1ValueChanged(app, event)
            value = app.OC_offButton_1.Value;
            switch value 
                case 1
                    app.OC_offButton_1.Text = "on";
                    app.OC_offButton_1.BackgroundColor = [0.39,0.83,0.07];                    
                case 0                    
                    app.OC_offButton_1.Text = "off";
                    app.OC_offButton_1.BackgroundColor = [0.96,0.96,0.96];
            end

            Open_circuit = ['[',num2str(not(app.OC_offButton_12.Value)),',',num2str(not(app.OC_offButton_11.Value)),',',num2str(not(app.OC_offButton_10.Value)),',',num2str(not(app.OC_offButton_9.Value)),',',num2str(not(app.OC_offButton_8.Value)),',',num2str(not(app.OC_offButton_7.Value)),',',num2str(not(app.OC_offButton_6.Value)),',',num2str(not(app.OC_offButton_5.Value)),',',num2str(not(app.OC_offButton_4.Value)),',',num2str(not(app.OC_offButton_3.Value)),',',num2str(not(app.OC_offButton_2.Value)),',',num2str(not(app.OC_offButton_1.Value)),']'];
            set_param('SystemModel/Instrumentation/Instrumented Signals/12-Cell/Open_Circuits_Value','Value',Open_circuit);
        end

        % Value changed function: OC_offButton_2
        function OC_offButton_2ValueChanged(app, event)
            value = app.OC_offButton_2.Value;
            switch value 
                case 1
                    app.OC_offButton_2.Text = "on";
                    app.OC_offButton_2.BackgroundColor = [0.39,0.83,0.07];                    
                case 0                    
                    app.OC_offButton_2.Text = "off";
                    app.OC_offButton_2.BackgroundColor = [0.96,0.96,0.96];
            end

            Open_circuit = ['[',num2str(not(app.OC_offButton_12.Value)),',',num2str(not(app.OC_offButton_11.Value)),',',num2str(not(app.OC_offButton_10.Value)),',',num2str(not(app.OC_offButton_9.Value)),',',num2str(not(app.OC_offButton_8.Value)),',',num2str(not(app.OC_offButton_7.Value)),',',num2str(not(app.OC_offButton_6.Value)),',',num2str(not(app.OC_offButton_5.Value)),',',num2str(not(app.OC_offButton_4.Value)),',',num2str(not(app.OC_offButton_3.Value)),',',num2str(not(app.OC_offButton_2.Value)),',',num2str(not(app.OC_offButton_1.Value)),']'];
            set_param('SystemModel/Instrumentation/Instrumented Signals/12-Cell/Open_Circuits_Value','Value',Open_circuit);
        end

        % Value changed function: OC_offButton_3
        function OC_offButton_3ValueChanged(app, event)
            value = app.OC_offButton_3.Value;
            switch value 
                case 1
                    app.OC_offButton_3.Text = "on";
                    app.OC_offButton_3.BackgroundColor = [0.39,0.83,0.07];                    
                case 0                    
                    app.OC_offButton_3.Text = "off";
                    app.OC_offButton_3.BackgroundColor = [0.96,0.96,0.96];
            end

            Open_circuit = ['[',num2str(not(app.OC_offButton_12.Value)),',',num2str(not(app.OC_offButton_11.Value)),',',num2str(not(app.OC_offButton_10.Value)),',',num2str(not(app.OC_offButton_9.Value)),',',num2str(not(app.OC_offButton_8.Value)),',',num2str(not(app.OC_offButton_7.Value)),',',num2str(not(app.OC_offButton_6.Value)),',',num2str(not(app.OC_offButton_5.Value)),',',num2str(not(app.OC_offButton_4.Value)),',',num2str(not(app.OC_offButton_3.Value)),',',num2str(not(app.OC_offButton_2.Value)),',',num2str(not(app.OC_offButton_1.Value)),']'];
            set_param('SystemModel/Instrumentation/Instrumented Signals/12-Cell/Open_Circuits_Value','Value',Open_circuit);
        end

        % Value changed function: OC_offButton_4
        function OC_offButton_4ValueChanged(app, event)
            value = app.OC_offButton_4.Value;
            switch value 
                case 1
                    app.OC_offButton_4.Text = "on";
                    app.OC_offButton_4.BackgroundColor = [0.39,0.83,0.07];                    
                case 0                    
                    app.OC_offButton_4.Text = "off";
                    app.OC_offButton_4.BackgroundColor = [0.96,0.96,0.96];
            end

            Open_circuit = ['[',num2str(not(app.OC_offButton_12.Value)),',',num2str(not(app.OC_offButton_11.Value)),',',num2str(not(app.OC_offButton_10.Value)),',',num2str(not(app.OC_offButton_9.Value)),',',num2str(not(app.OC_offButton_8.Value)),',',num2str(not(app.OC_offButton_7.Value)),',',num2str(not(app.OC_offButton_6.Value)),',',num2str(not(app.OC_offButton_5.Value)),',',num2str(not(app.OC_offButton_4.Value)),',',num2str(not(app.OC_offButton_3.Value)),',',num2str(not(app.OC_offButton_2.Value)),',',num2str(not(app.OC_offButton_1.Value)),']'];
            set_param('SystemModel/Instrumentation/Instrumented Signals/12-Cell/Open_Circuits_Value','Value',Open_circuit);
        end

        % Value changed function: OC_offButton_5
        function OC_offButton_5ValueChanged(app, event)
            value = app.OC_offButton_5.Value;
            switch value 
                case 1
                    app.OC_offButton_5.Text = "on";
                    app.OC_offButton_5.BackgroundColor = [0.39,0.83,0.07];                    
                case 0                    
                    app.OC_offButton_5.Text = "off";
                    app.OC_offButton_5.BackgroundColor = [0.96,0.96,0.96];
            end

            Open_circuit = ['[',num2str(not(app.OC_offButton_12.Value)),',',num2str(not(app.OC_offButton_11.Value)),',',num2str(not(app.OC_offButton_10.Value)),',',num2str(not(app.OC_offButton_9.Value)),',',num2str(not(app.OC_offButton_8.Value)),',',num2str(not(app.OC_offButton_7.Value)),',',num2str(not(app.OC_offButton_6.Value)),',',num2str(not(app.OC_offButton_5.Value)),',',num2str(not(app.OC_offButton_4.Value)),',',num2str(not(app.OC_offButton_3.Value)),',',num2str(not(app.OC_offButton_2.Value)),',',num2str(not(app.OC_offButton_1.Value)),']'];
            set_param('SystemModel/Instrumentation/Instrumented Signals/12-Cell/Open_Circuits_Value','Value',Open_circuit);
        end

        % Value changed function: OC_offButton_6
        function OC_offButton_6ValueChanged(app, event)
            value = app.OC_offButton_6.Value;
            switch value 
                case 1
                    app.OC_offButton_6.Text = "on";
                    app.OC_offButton_6.BackgroundColor = [0.39,0.83,0.07];                    
                case 0                    
                    app.OC_offButton_6.Text = "off";
                    app.OC_offButton_6.BackgroundColor = [0.96,0.96,0.96];
            end

            Open_circuit = ['[',num2str(not(app.OC_offButton_12.Value)),',',num2str(not(app.OC_offButton_11.Value)),',',num2str(not(app.OC_offButton_10.Value)),',',num2str(not(app.OC_offButton_9.Value)),',',num2str(not(app.OC_offButton_8.Value)),',',num2str(not(app.OC_offButton_7.Value)),',',num2str(not(app.OC_offButton_6.Value)),',',num2str(not(app.OC_offButton_5.Value)),',',num2str(not(app.OC_offButton_4.Value)),',',num2str(not(app.OC_offButton_3.Value)),',',num2str(not(app.OC_offButton_2.Value)),',',num2str(not(app.OC_offButton_1.Value)),']'];
            set_param('SystemModel/Instrumentation/Instrumented Signals/12-Cell/Open_Circuits_Value','Value',Open_circuit);
        end

        % Value changed function: OC_offButton_7
        function OC_offButton_7ValueChanged(app, event)
            value = app.OC_offButton_7.Value;
            switch value 
                case 1
                    app.OC_offButton_7.Text = "on";
                    app.OC_offButton_7.BackgroundColor = [0.39,0.83,0.07];                    
                case 0                    
                    app.OC_offButton_7.Text = "off";
                    app.OC_offButton_7.BackgroundColor = [0.96,0.96,0.96];
            end

            Open_circuit = ['[',num2str(not(app.OC_offButton_12.Value)),',',num2str(not(app.OC_offButton_11.Value)),',',num2str(not(app.OC_offButton_10.Value)),',',num2str(not(app.OC_offButton_9.Value)),',',num2str(not(app.OC_offButton_8.Value)),',',num2str(not(app.OC_offButton_7.Value)),',',num2str(not(app.OC_offButton_6.Value)),',',num2str(not(app.OC_offButton_5.Value)),',',num2str(not(app.OC_offButton_4.Value)),',',num2str(not(app.OC_offButton_3.Value)),',',num2str(not(app.OC_offButton_2.Value)),',',num2str(not(app.OC_offButton_1.Value)),']'];
            set_param('SystemModel/Instrumentation/Instrumented Signals/12-Cell/Open_Circuits_Value','Value',Open_circuit);
        end

        % Value changed function: OC_offButton_8
        function OC_offButton_8ValueChanged(app, event)
            value = app.OC_offButton_8.Value;
            switch value 
                case 1
                    app.OC_offButton_8.Text = "on";
                    app.OC_offButton_8.BackgroundColor = [0.39,0.83,0.07];                    
                case 0                    
                    app.OC_offButton_8.Text = "off";
                    app.OC_offButton_8.BackgroundColor = [0.96,0.96,0.96];
            end

            Open_circuit = ['[',num2str(not(app.OC_offButton_12.Value)),',',num2str(not(app.OC_offButton_11.Value)),',',num2str(not(app.OC_offButton_10.Value)),',',num2str(not(app.OC_offButton_9.Value)),',',num2str(not(app.OC_offButton_8.Value)),',',num2str(not(app.OC_offButton_7.Value)),',',num2str(not(app.OC_offButton_6.Value)),',',num2str(not(app.OC_offButton_5.Value)),',',num2str(not(app.OC_offButton_4.Value)),',',num2str(not(app.OC_offButton_3.Value)),',',num2str(not(app.OC_offButton_2.Value)),',',num2str(not(app.OC_offButton_1.Value)),']'];
            set_param('SystemModel/Instrumentation/Instrumented Signals/12-Cell/Open_Circuits_Value','Value',Open_circuit);
        end

        % Value changed function: OC_offButton_9
        function OC_offButton_9ValueChanged(app, event)
            value = app.OC_offButton_9.Value;
            switch value 
                case 1
                    app.OC_offButton_9.Text = "on";
                    app.OC_offButton_9.BackgroundColor = [0.39,0.83,0.07];                    
                case 0                    
                    app.OC_offButton_9.Text = "off";
                    app.OC_offButton_9.BackgroundColor = [0.96,0.96,0.96];
            end

            Open_circuit = ['[',num2str(not(app.OC_offButton_12.Value)),',',num2str(not(app.OC_offButton_11.Value)),',',num2str(not(app.OC_offButton_10.Value)),',',num2str(not(app.OC_offButton_9.Value)),',',num2str(not(app.OC_offButton_8.Value)),',',num2str(not(app.OC_offButton_7.Value)),',',num2str(not(app.OC_offButton_6.Value)),',',num2str(not(app.OC_offButton_5.Value)),',',num2str(not(app.OC_offButton_4.Value)),',',num2str(not(app.OC_offButton_3.Value)),',',num2str(not(app.OC_offButton_2.Value)),',',num2str(not(app.OC_offButton_1.Value)),']'];
            set_param('SystemModel/Instrumentation/Instrumented Signals/12-Cell/Open_Circuits_Value','Value',Open_circuit);
        end

        % Value changed function: OC_offButton_10
        function OC_offButton_10ValueChanged(app, event)
            value = app.OC_offButton_10.Value;
            switch value 
                case 1
                    app.OC_offButton_10.Text = "on";
                    app.OC_offButton_10.BackgroundColor = [0.39,0.83,0.07];                    
                case 0                    
                    app.OC_offButton_10.Text = "off";
                    app.OC_offButton_10.BackgroundColor = [0.96,0.96,0.96];
            end

            Open_circuit = ['[',num2str(not(app.OC_offButton_12.Value)),',',num2str(not(app.OC_offButton_11.Value)),',',num2str(not(app.OC_offButton_10.Value)),',',num2str(not(app.OC_offButton_9.Value)),',',num2str(not(app.OC_offButton_8.Value)),',',num2str(not(app.OC_offButton_7.Value)),',',num2str(not(app.OC_offButton_6.Value)),',',num2str(not(app.OC_offButton_5.Value)),',',num2str(not(app.OC_offButton_4.Value)),',',num2str(not(app.OC_offButton_3.Value)),',',num2str(not(app.OC_offButton_2.Value)),',',num2str(not(app.OC_offButton_1.Value)),']'];
            set_param('SystemModel/Instrumentation/Instrumented Signals/12-Cell/Open_Circuits_Value','Value',Open_circuit);
        end

        % Value changed function: OC_offButton_11
        function OC_offButton_11ValueChanged(app, event)
            value = app.OC_offButton_11.Value;
            switch value 
                case 1
                    app.OC_offButton_11.Text = "on";
                    app.OC_offButton_11.BackgroundColor = [0.39,0.83,0.07];                    
                case 0                    
                    app.OC_offButton_11.Text = "off";
                    app.OC_offButton_11.BackgroundColor = [0.96,0.96,0.96];
            end

            Open_circuit = ['[',num2str(not(app.OC_offButton_12.Value)),',',num2str(not(app.OC_offButton_11.Value)),',',num2str(not(app.OC_offButton_10.Value)),',',num2str(not(app.OC_offButton_9.Value)),',',num2str(not(app.OC_offButton_8.Value)),',',num2str(not(app.OC_offButton_7.Value)),',',num2str(not(app.OC_offButton_6.Value)),',',num2str(not(app.OC_offButton_5.Value)),',',num2str(not(app.OC_offButton_4.Value)),',',num2str(not(app.OC_offButton_3.Value)),',',num2str(not(app.OC_offButton_2.Value)),',',num2str(not(app.OC_offButton_1.Value)),']'];
            set_param('SystemModel/Instrumentation/Instrumented Signals/12-Cell/Open_Circuits_Value','Value',Open_circuit);
        end

        % Value changed function: OC_offButton_12
        function OC_offButton_12ValueChanged(app, event)
            value = app.OC_offButton_12.Value;
            switch value 
                case 1
                    app.OC_offButton_12.Text = "on";
                    app.OC_offButton_12.BackgroundColor = [0.39,0.83,0.07];                    
                case 0                    
                    app.OC_offButton_12.Text = "off";
                    app.OC_offButton_12.BackgroundColor = [0.96,0.96,0.96];
            end

            Open_circuit = ['[',num2str(not(app.OC_offButton_12.Value)),',',num2str(not(app.OC_offButton_11.Value)),',',num2str(not(app.OC_offButton_10.Value)),',',num2str(not(app.OC_offButton_9.Value)),',',num2str(not(app.OC_offButton_8.Value)),',',num2str(not(app.OC_offButton_7.Value)),',',num2str(not(app.OC_offButton_6.Value)),',',num2str(not(app.OC_offButton_5.Value)),',',num2str(not(app.OC_offButton_4.Value)),',',num2str(not(app.OC_offButton_3.Value)),',',num2str(not(app.OC_offButton_2.Value)),',',num2str(not(app.OC_offButton_1.Value)),']'];
            set_param('SystemModel/Instrumentation/Instrumented Signals/12-Cell/Open_Circuits_Value','Value',Open_circuit);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BMSUIFigure = uifigure('Visible', 'off');
            app.BMSUIFigure.Name = 'BMS';
            app.GridLayout = uigridlayout(app.BMSUIFigure);
            app.ConnectButton = slrealtime.ui.control.ConnectButton(app.GridLayout);
            app.TargetSelector = slrealtime.ui.control.TargetSelector(app.GridLayout);
            app.RebootButton = slrealtime.ui.control.RebootButton(app.GridLayout);
            app.SimulationModeButtonGroup = uibuttongroup(app.GridLayout);
            app.SimulationModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SimulationModeButtonGroupSelectionChanged, true);
            app.SimulationModeButtonGroup.Tooltip = {'Select Simulation Mode'};
            app.SimulationModeButtonGroup.Title = 'Simulation Mode';
            app.ModelintheLoopButton = uiradiobutton(app.SimulationModeButtonGroup);
            app.ModelintheLoopButton.Text = 'Model in the Loop';
            app.ModelintheLoopButton.Value = true;
            app.HardwareintheLoopButton = uiradiobutton(app.SimulationModeButtonGroup);
            app.HardwareintheLoopButton.Text = 'Hardware in the Loop';
            app.StopTimeLabel = uilabel(app.GridLayout);
            app.StopTimeLabel.Text = 'StopTime';
            app.LoadButton = slrealtime.ui.control.LoadButton(app.GridLayout);
            app.LoadButton.LoadText = {'Load', 'Application'};
            app.LoadButton.ShowLoadedApplication = false;
            app.StatusBar = slrealtime.ui.control.StatusBar(app.GridLayout);
            app.UpdateButton = slrealtime.ui.control.UpdateButton(app.GridLayout);
            app.UpdateButton.UpdateText = {'Update', 'Software'};
            app.DesktopSimStartButton = uibutton(app.GridLayout, 'state');
            app.DesktopSimStartButton.ValueChangedFcn = createCallbackFcn(app, @DesktopSimStartButtonValueChanged, true);
            app.DesktopSimStartButton.Tooltip = {'Run on Desktop'};
            app.DesktopSimStartButton.Icon = 'slrtRunIcon.png';
            app.DesktopSimStartButton.Text = {'Start '; ''; '(On Host)'};
            app.SystemLog = slrealtime.ui.control.SystemLog(app.GridLayout);
            app.TabGroupMain = uitabgroup(app.GridLayout);
            app.Controls = uitab(app.TabGroupMain);
            app.Controls.Title = 'Main';
            app.GridLayout2 = uigridlayout(app.Controls);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Cell balancing currents [mA]')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Current [mA]')
            app.UIAxes.XLim = [0 Inf];
            app.UIAxes.XLimitMethod = 'padded';
            app.UIAxes.YLimitMethod = 'padded';
            app.UIAxes.ZTick = [];
            app.UI = uiimage(app.GridLayout2);
            app.UI.ImageSource = 'UI_image_app.svg';
            app.BatteryModuleCurrentAGauge = uigauge(app.GridLayout2, 'circular');
            app.BatteryModuleCurrentAGauge.Limits = [-120 120];
            app.BatteryModuleVoltageVGaugeLabel = uilabel(app.GridLayout2);
            app.BatteryModuleVoltageVGaugeLabel.Text = 'Battery Module Voltage [V]';
            app.BatteryModuleVoltageVGauge = uigauge(app.GridLayout2, 'circular');
            app.BatteryModuleCurrentAGauge_Label = uilabel(app.GridLayout2);
            app.BatteryModuleCurrentAGauge_Label.Text = 'Battery Module Current [A]';
            app.FaultsdetectedLabel = uilabel(app.GridLayout2);
            app.FaultsdetectedLabel.Text = 'Faults detected';
            app.DischargemAGauge = uigauge(app.GridLayout2, 'circular');
            app.DischargemAGauge.Limits = [-100 0];
            app.ChargeGauge = uigauge(app.GridLayout2, 'circular');
            app.ChargeGauge.Limits = [-100 100];
            app.BatteryModulueVoltageEditField = uieditfield(app.GridLayout2, 'numeric');
            app.BatteryModulueVoltageEditField.Editable = 'off';
            app.BatteryModulueCurrentEditField = uieditfield(app.GridLayout2, 'numeric');
            app.BatteryModulueCurrentEditField.Editable = 'off';
            app.DischargeEditField = uieditfield(app.GridLayout2, 'numeric');
            app.DischargeEditField.Editable = 'off';
            app.ChargeEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ChargeEditField.Editable = 'off';
            app.ConfigurationButton = uibutton(app.GridLayout2, 'push');
            app.ConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigurationButtonPushed, true);
            app.ConfigurationButton.Tooltip = {'Force V/I values or add faults'};
            app.ConfigurationButton.Text = 'Configuration';
            app.overCurrent = uibutton(app.GridLayout2, 'state');
            app.overCurrent.ValueChangedFcn = createCallbackFcn(app, @overCurrentValueChanged, true);
            app.overCurrent.Visible = 'off';
            app.overCurrent.Text = 'Button';
            app.highTemp = uibutton(app.GridLayout2, 'state');
            app.highTemp.ValueChangedFcn = createCallbackFcn(app, @highTempValueChanged, true);
            app.highTemp.Visible = 'off';
            app.highTemp.Text = 'Button2';
            app.lowTemp = uibutton(app.GridLayout2, 'state');
            app.lowTemp.ValueChangedFcn = createCallbackFcn(app, @lowTempValueChanged, true);
            app.lowTemp.Visible = 'off';
            app.lowTemp.Text = 'Button3';
            app.overVoltage = uibutton(app.GridLayout2, 'state');
            app.overVoltage.ValueChangedFcn = createCallbackFcn(app, @overVoltageValueChanged, true);
            app.overVoltage.Visible = 'off';
            app.overVoltage.Text = 'Button4';
            app.underVoltage = uibutton(app.GridLayout2, 'state');
            app.underVoltage.ValueChangedFcn = createCallbackFcn(app, @underVoltageValueChanged, true);
            app.underVoltage.Visible = 'off';
            app.underVoltage.Text = 'Button5';
            app.ChargeDischargeCurrentAEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ChargeDischargeCurrentAEditField.Limits = [-120 120];
            app.ChargeDischargeCurrentAEditField.ValueChangedFcn = createCallbackFcn(app, @ChargeDischargeCurrentmAEditFieldValueChanged, true);
            app.ChargeDischargeCurrentAEditField.Value = -20;
            app.I_TextArea_1 = uitextarea(app.GridLayout2);
            app.V_TextArea_1 = uitextarea(app.GridLayout2);
            app.I_TextArea_2 = uitextarea(app.GridLayout2);
            app.V_TextArea_2 = uitextarea(app.GridLayout2);
            app.T_TextArea_1 = uitextarea(app.GridLayout2);
            app.SOC_TextArea_1 = uitextarea(app.GridLayout2);
            app.T_TextArea_2 = uitextarea(app.GridLayout2);
            app.SOC_TextArea_2 = uitextarea(app.GridLayout2);
            app.I_TextArea_3 = uitextarea(app.GridLayout2);
            app.V_TextArea_3 = uitextarea(app.GridLayout2);
            app.T_TextArea_3 = uitextarea(app.GridLayout2);
            app.SOC_TextArea_3 = uitextarea(app.GridLayout2);
            app.Gauge_1 = uigauge(app.GridLayout2, 'linear');
            app.Gauge_1.MajorTicks = [0 100];
            app.Gauge_1.Tooltip = {'Battery Cell 1'};
            app.Gauge_1.Value = 50;
            app.Gauge_2 = uigauge(app.GridLayout2, 'linear');
            app.Gauge_2.MajorTicks = [0 100];
            app.Gauge_2.Tooltip = {'Battery Cell 2'};
            app.Gauge_2.Value = 50;
            app.Gauge_3 = uigauge(app.GridLayout2, 'linear');
            app.Gauge_3.MajorTicks = [0 100];
            app.Gauge_3.Tooltip = {'Battery Cell 3'};
            app.Gauge_3.Value = 50;
            app.I_TextArea_4 = uitextarea(app.GridLayout2);
            app.V_TextArea_4 = uitextarea(app.GridLayout2);
            app.T_TextArea_4 = uitextarea(app.GridLayout2);
            app.SOC_TextArea_4 = uitextarea(app.GridLayout2);
            app.Gauge_4 = uigauge(app.GridLayout2, 'linear');
            app.Gauge_4.MajorTicks = [0 100];
            app.Gauge_4.Tooltip = {'Battery Cell 4'};
            app.Gauge_4.Value = 50;
            app.Gauge_12 = uigauge(app.GridLayout2, 'linear');
            app.Gauge_12.MajorTicks = [0 100];
            app.Gauge_12.Tooltip = {'Battery Cell 12'};
            app.Gauge_12.Value = 50;
            app.I_TextArea_12 = uitextarea(app.GridLayout2);
            app.Gauge_11 = uigauge(app.GridLayout2, 'linear');
            app.Gauge_11.MajorTicks = [0 100];
            app.Gauge_11.Tooltip = {'Battery Cell 11'};
            app.Gauge_11.Value = 50;
            app.I_TextArea_11 = uitextarea(app.GridLayout2);
            app.Gauge_10 = uigauge(app.GridLayout2, 'linear');
            app.Gauge_10.MajorTicks = [0 100];
            app.Gauge_10.Tooltip = {'Battery Cell 10'};
            app.Gauge_10.Value = 50;
            app.I_TextArea_10 = uitextarea(app.GridLayout2);
            app.Gauge_9 = uigauge(app.GridLayout2, 'linear');
            app.Gauge_9.MajorTicks = [0 100];
            app.Gauge_9.Tooltip = {'Battery Cell 9'};
            app.Gauge_9.Value = 50;
            app.V_TextArea_12 = uitextarea(app.GridLayout2);
            app.V_TextArea_11 = uitextarea(app.GridLayout2);
            app.V_TextArea_10 = uitextarea(app.GridLayout2);
            app.I_TextArea_9 = uitextarea(app.GridLayout2);
            app.V_TextArea_9 = uitextarea(app.GridLayout2);
            app.T_TextArea_12 = uitextarea(app.GridLayout2);
            app.T_TextArea_11 = uitextarea(app.GridLayout2);
            app.T_TextArea_10 = uitextarea(app.GridLayout2);
            app.T_TextArea_9 = uitextarea(app.GridLayout2);
            app.SOC_TextArea_12 = uitextarea(app.GridLayout2);
            app.SOC_TextArea_11 = uitextarea(app.GridLayout2);
            app.SOC_TextArea_10 = uitextarea(app.GridLayout2);
            app.SOC_TextArea_9 = uitextarea(app.GridLayout2);
            app.I_TextArea_5 = uitextarea(app.GridLayout2);
            app.V_TextArea_5 = uitextarea(app.GridLayout2);
            app.T_TextArea_5 = uitextarea(app.GridLayout2);
            app.SOC_TextArea_5 = uitextarea(app.GridLayout2);
            app.I_TextArea_8 = uitextarea(app.GridLayout2);
            app.V_TextArea_8 = uitextarea(app.GridLayout2);
            app.T_TextArea_8 = uitextarea(app.GridLayout2);
            app.SOC_TextArea_8 = uitextarea(app.GridLayout2);
            app.Gauge_5 = uigauge(app.GridLayout2, 'linear');
            app.Gauge_5.MajorTicks = [0 100];
            app.Gauge_5.Tooltip = {'Battery Cell 5'};
            app.Gauge_5.Value = 50;
            app.Gauge_8 = uigauge(app.GridLayout2, 'linear');
            app.Gauge_8.MajorTicks = [0 100];
            app.Gauge_8.Tooltip = {'Battery Cell 8'};
            app.Gauge_8.Value = 50;
            app.I_TextArea_6 = uitextarea(app.GridLayout2);
            app.V_TextArea_6 = uitextarea(app.GridLayout2);
            app.T_TextArea_6 = uitextarea(app.GridLayout2);
            app.SOC_TextArea_6 = uitextarea(app.GridLayout2);
            app.I_TextArea_7 = uitextarea(app.GridLayout2);
            app.V_TextArea_7 = uitextarea(app.GridLayout2);
            app.T_TextArea_7 = uitextarea(app.GridLayout2);
            app.SOC_TextArea_7 = uitextarea(app.GridLayout2);
            app.Gauge_6 = uigauge(app.GridLayout2, 'linear');
            app.Gauge_6.MajorTicks = [0 100];
            app.Gauge_6.Tooltip = {'Battery Cell 6'};
            app.Gauge_6.Value = 50;
            app.Gauge_7 = uigauge(app.GridLayout2, 'linear');
            app.Gauge_7.MajorTicks = [0 100];
            app.Gauge_7.Tooltip = {'Battery Cell 7'};
            app.Gauge_7.Value = 50;
            app.Tank = uiimage(app.GridLayout2);
            app.Tank.ImageSource = 'Tank.png';
            app.Slider = uislider(app.GridLayout2);
            app.Slider.Limits = [-120 120];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Tooltip = {'Enter Current (mA)'};
            app.BMU = uiimage(app.GridLayout2);
            app.BMU.ImageSource = 'MCU.png';
            app.StateofChargeSOCGauge = uigauge(app.GridLayout2, 'circular');
            app.SOCEditField_1 = uieditfield(app.GridLayout2, 'numeric');
            app.SOCEditField_1.Limits = [0 100];
            app.SOCEditField_1.Editable = 'off';
            app.CurrentLimitsALabel = uilabel(app.GridLayout2);
            app.CurrentLimitsALabel.Text = 'Current Limits [A]';
            app.SOCLabel = uilabel(app.GridLayout2);
            app.SOCLabel.Text = 'SOC [%]';
            app.ChargeImbalancemVGauge = uigauge(app.GridLayout2, 'semicircular');
            app.ChargeImbalancemVGauge.Limits = [-1000 1000];
            app.ChargeImbalanceEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ChargeImbalanceEditField.Editable = 'off';
            app.BatteryModulueVoltageEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.BatteryModulueVoltageEditField_2.Editable = 'off';
            app.VLabel = uilabel(app.GridLayout2);
            app.VLabel.Text = 'V';
            app.ALabel = uilabel(app.GridLayout2);
            app.ALabel.Text = 'A';
            app.DischargeLabel = uilabel(app.GridLayout2);
            app.DischargeLabel.Text = 'Discharge';
            app.ChargeLabel = uilabel(app.GridLayout2);
            app.ChargeLabel.Text = 'Charge';
            app.BatteryModulueCurrentEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.BatteryModulueCurrentEditField_2.Editable = 'off';
            app.TestScenarioDropDown_11Label = uilabel(app.GridLayout2);
            app.TestScenarioDropDown_11Label.Text = 'Test Scenario';
            app.TestScenarioDropDown = uidropdown(app.GridLayout2);
            app.TestScenarioDropDown.Items = {'Standby', 'Driving', 'Charging'};
            app.TestScenarioDropDown.ItemsData = {'0', '1', '2'};
            app.TestScenarioDropDown.Editable = 'on';
            app.TestScenarioDropDown.ValueChangedFcn = createCallbackFcn(app, @TestScenarioDropDownValueChanged, true);
            app.TestScenarioDropDown.Placeholder = 'Enum';
            app.TestScenarioDropDown.Value = '0';
            app.AmbientTemperatureCLabel = uilabel(app.GridLayout2);
            app.AmbientTemperatureCLabel.Text = 'Ambient Temperature [°C]';
            app.AmbientTemperatureCEditField = uieditfield(app.GridLayout2, 'numeric');
            app.AmbientTemperatureCEditField.ValueChangedFcn = createCallbackFcn(app, @AmbientTemperatureCEditFieldValueChanged, true);
            app.AmbientTemperatureCEditField.Value = 25;
            app.CellhightemperatureLamp_11Label = uilabel(app.GridLayout2);
            app.CellhightemperatureLamp_11Label.Text = 'Cell high temperature';
            app.CellhightemperatureLamp = uilamp(app.GridLayout2);
            app.CelllowtemperatureLamp_11Label = uilabel(app.GridLayout2);
            app.CelllowtemperatureLamp_11Label.Text = 'Cell low temperature';
            app.CelllowtemperatureLamp = uilamp(app.GridLayout2);
            app.CellovervoltageLabel = uilabel(app.GridLayout2);
            app.CellovervoltageLabel.Text = 'Cell over voltage';
            app.CellovervoltageLamp = uilamp(app.GridLayout2);
            app.CellundervoltageLabel = uilabel(app.GridLayout2);
            app.CellundervoltageLabel.Text = 'Cell under voltage';
            app.CellundervoltageLamp = uilamp(app.GridLayout2);
            app.ModuleovercurrentLampLabel = uilabel(app.GridLayout2);
            app.ModuleovercurrentLampLabel.Text = 'Module over current';
            app.ModuleovercurrentLamp = uilamp(app.GridLayout2);
            app.ChargeImbalancemVLabel = uilabel(app.GridLayout2);
            app.ChargeImbalancemVLabel.Text = 'Charge Imbalance [mV]';
            app.ConfigurationTab = uitab(app.TabGroupMain);
            app.ConfigurationTab.Title = 'Configuration';
            app.GridLayout3 = uigridlayout(app.ConfigurationTab);
            app.Configuration = uiimage(app.GridLayout3);
            app.Configuration.ImageSource = 'Cells-12.jpg';
            app.T_EditField_1 = uieditfield(app.GridLayout3, 'text');
            app.T_EditField_1.ValueChangedFcn = createCallbackFcn(app, @T_EditField_1ValueChanged, true);
            app.T_EditField_1.Value = '25';
            app.T_EditField_2 = uieditfield(app.GridLayout3, 'text');
            app.T_EditField_2.ValueChangedFcn = createCallbackFcn(app, @T_EditField_2ValueChanged, true);
            app.T_EditField_2.Value = '25';
            app.T_EditField_3 = uieditfield(app.GridLayout3, 'text');
            app.T_EditField_3.ValueChangedFcn = createCallbackFcn(app, @T_EditField_3ValueChanged, true);
            app.T_EditField_3.Value = '25';
            app.T_EditField_4 = uieditfield(app.GridLayout3, 'text');
            app.T_EditField_4.ValueChangedFcn = createCallbackFcn(app, @T_EditField_4ValueChanged, true);
            app.T_EditField_4.Value = '25';
            app.T_EditField_5 = uieditfield(app.GridLayout3, 'text');
            app.T_EditField_5.ValueChangedFcn = createCallbackFcn(app, @T_EditField_5ValueChanged, true);
            app.T_EditField_5.Value = '25';
            app.T_EditField_6 = uieditfield(app.GridLayout3, 'text');
            app.T_EditField_6.ValueChangedFcn = createCallbackFcn(app, @T_EditField_6ValueChanged, true);
            app.T_EditField_6.Value = '25';
            app.T_EditField_7 = uieditfield(app.GridLayout3, 'text');
            app.T_EditField_7.ValueChangedFcn = createCallbackFcn(app, @T_EditField_7ValueChanged, true);
            app.T_EditField_7.Value = '25';
            app.T_EditField_8 = uieditfield(app.GridLayout3, 'text');
            app.T_EditField_8.ValueChangedFcn = createCallbackFcn(app, @T_EditField_8ValueChanged, true);
            app.T_EditField_8.Value = '25';
            app.T_EditField_9 = uieditfield(app.GridLayout3, 'text');
            app.T_EditField_9.ValueChangedFcn = createCallbackFcn(app, @T_EditField_9ValueChanged, true);
            app.T_EditField_9.Value = '25';
            app.T_EditField_10 = uieditfield(app.GridLayout3, 'text');
            app.T_EditField_10.ValueChangedFcn = createCallbackFcn(app, @T_EditField_10ValueChanged, true);
            app.T_EditField_10.Value = '25';
            app.T_EditField_11 = uieditfield(app.GridLayout3, 'text');
            app.T_EditField_11.ValueChangedFcn = createCallbackFcn(app, @T_EditField_11ValueChanged, true);
            app.T_EditField_11.Value = '25';
            app.T_EditField_12 = uieditfield(app.GridLayout3, 'text');
            app.T_EditField_12.ValueChangedFcn = createCallbackFcn(app, @T_EditField_12ValueChanged, true);
            app.T_EditField_12.Value = '25';
            app.V_EditField_1 = uieditfield(app.GridLayout3, 'text');
            app.V_EditField_1.ValueChangedFcn = createCallbackFcn(app, @V_EditField_1_1ValueChanged, true);
            app.V_EditField_1.Value = '4';
            app.V_EditField_2 = uieditfield(app.GridLayout3, 'text');
            app.V_EditField_2.ValueChangedFcn = createCallbackFcn(app, @V_EditField_2ValueChanged, true);
            app.V_EditField_2.Value = '4';
            app.V_EditField_3 = uieditfield(app.GridLayout3, 'text');
            app.V_EditField_3.ValueChangedFcn = createCallbackFcn(app, @V_EditField_3ValueChanged, true);
            app.V_EditField_3.Value = '4';
            app.V_EditField_4 = uieditfield(app.GridLayout3, 'text');
            app.V_EditField_4.ValueChangedFcn = createCallbackFcn(app, @V_EditField_4ValueChanged, true);
            app.V_EditField_4.Value = '4';
            app.V_EditField_5 = uieditfield(app.GridLayout3, 'text');
            app.V_EditField_5.ValueChangedFcn = createCallbackFcn(app, @V_EditField_5ValueChanged, true);
            app.V_EditField_5.Value = '4';
            app.V_EditField_6 = uieditfield(app.GridLayout3, 'text');
            app.V_EditField_6.ValueChangedFcn = createCallbackFcn(app, @V_EditField_6ValueChanged, true);
            app.V_EditField_6.Value = '4';
            app.V_EditField_7 = uieditfield(app.GridLayout3, 'text');
            app.V_EditField_7.ValueChangedFcn = createCallbackFcn(app, @V_EditField_7ValueChanged, true);
            app.V_EditField_7.Value = '4';
            app.V_EditField_8 = uieditfield(app.GridLayout3, 'text');
            app.V_EditField_8.ValueChangedFcn = createCallbackFcn(app, @V_EditField_8ValueChanged, true);
            app.V_EditField_8.Value = '4';
            app.V_EditField_9 = uieditfield(app.GridLayout3, 'text');
            app.V_EditField_9.ValueChangedFcn = createCallbackFcn(app, @V_EditField_9ValueChanged, true);
            app.V_EditField_9.Value = '4';
            app.V_EditField_10 = uieditfield(app.GridLayout3, 'text');
            app.V_EditField_10.ValueChangedFcn = createCallbackFcn(app, @V_EditField_10ValueChanged, true);
            app.V_EditField_10.Value = '4';
            app.V_EditField_11 = uieditfield(app.GridLayout3, 'text');
            app.V_EditField_11.ValueChangedFcn = createCallbackFcn(app, @V_EditField_11ValueChanged, true);
            app.V_EditField_11.Value = '4';
            app.V_EditField_12 = uieditfield(app.GridLayout3, 'text');
            app.V_EditField_12.ValueChangedFcn = createCallbackFcn(app, @V_EditField_12ValueChanged, true);
            app.V_EditField_12.Value = '4';
            app.T_offButton_1 = uibutton(app.GridLayout3, 'state');
            app.T_offButton_1.ValueChangedFcn = createCallbackFcn(app, @T_offButton_1ValueChanged, true);
            app.T_offButton_1.Text = 'off';
            app.V_offButton_1 = uibutton(app.GridLayout3, 'state');
            app.V_offButton_1.ValueChangedFcn = createCallbackFcn(app, @V_offButton_1ValueChanged, true);
            app.V_offButton_1.Text = 'off';
            app.T_offButton_2 = uibutton(app.GridLayout3, 'state');
            app.T_offButton_2.ValueChangedFcn = createCallbackFcn(app, @T_offButton_2ValueChanged, true);
            app.T_offButton_2.Text = 'off';
            app.V_offButton_2 = uibutton(app.GridLayout3, 'state');
            app.V_offButton_2.ValueChangedFcn = createCallbackFcn(app, @V_offButton_2ValueChanged, true);
            app.V_offButton_2.Text = 'off';
            app.T_offButton_3 = uibutton(app.GridLayout3, 'state');
            app.T_offButton_3.ValueChangedFcn = createCallbackFcn(app, @T_offButton_3ValueChanged, true);
            app.T_offButton_3.Text = 'off';
            app.V_offButton_3 = uibutton(app.GridLayout3, 'state');
            app.V_offButton_3.ValueChangedFcn = createCallbackFcn(app, @V_offButton_3ValueChanged, true);
            app.V_offButton_3.Text = 'off';
            app.T_offButton_4 = uibutton(app.GridLayout3, 'state');
            app.T_offButton_4.ValueChangedFcn = createCallbackFcn(app, @T_offButton_4ValueChanged, true);
            app.T_offButton_4.Text = 'off';
            app.V_offButton_4 = uibutton(app.GridLayout3, 'state');
            app.V_offButton_4.ValueChangedFcn = createCallbackFcn(app, @V_offButton_4ValueChanged, true);
            app.V_offButton_4.Text = 'off';
            app.T_offButton_5 = uibutton(app.GridLayout3, 'state');
            app.T_offButton_5.ValueChangedFcn = createCallbackFcn(app, @T_offButton_5ValueChanged, true);
            app.T_offButton_5.Text = 'off';
            app.V_offButton_5 = uibutton(app.GridLayout3, 'state');
            app.V_offButton_5.ValueChangedFcn = createCallbackFcn(app, @V_offButton_5ValueChanged, true);
            app.V_offButton_5.Text = 'off';
            app.T_offButton_6 = uibutton(app.GridLayout3, 'state');
            app.T_offButton_6.ValueChangedFcn = createCallbackFcn(app, @T_offButton_6ValueChanged, true);
            app.T_offButton_6.Text = 'off';
            app.V_offButton_6 = uibutton(app.GridLayout3, 'state');
            app.V_offButton_6.ValueChangedFcn = createCallbackFcn(app, @V_offButton_6ValueChanged, true);
            app.V_offButton_6.Text = 'off';
            app.T_offButton_7 = uibutton(app.GridLayout3, 'state');
            app.T_offButton_7.ValueChangedFcn = createCallbackFcn(app, @T_offButton_7ValueChanged, true);
            app.T_offButton_7.Text = 'off';
            app.V_offButton_7 = uibutton(app.GridLayout3, 'state');
            app.V_offButton_7.ValueChangedFcn = createCallbackFcn(app, @V_offButton_7ValueChanged, true);
            app.V_offButton_7.Text = 'off';
            app.T_offButton_8 = uibutton(app.GridLayout3, 'state');
            app.T_offButton_8.ValueChangedFcn = createCallbackFcn(app, @T_offButton_8ValueChanged, true);
            app.T_offButton_8.Text = 'off';
            app.V_offButton_8 = uibutton(app.GridLayout3, 'state');
            app.V_offButton_8.ValueChangedFcn = createCallbackFcn(app, @V_offButton_8ValueChanged, true);
            app.V_offButton_8.Text = 'off';
            app.T_offButton_9 = uibutton(app.GridLayout3, 'state');
            app.T_offButton_9.ValueChangedFcn = createCallbackFcn(app, @T_offButton_9ValueChanged, true);
            app.T_offButton_9.Text = 'off';
            app.V_offButton_9 = uibutton(app.GridLayout3, 'state');
            app.V_offButton_9.ValueChangedFcn = createCallbackFcn(app, @V_offButton_9ValueChanged, true);
            app.V_offButton_9.Text = 'off';
            app.T_offButton_10 = uibutton(app.GridLayout3, 'state');
            app.T_offButton_10.ValueChangedFcn = createCallbackFcn(app, @T_offButton_10ValueChanged, true);
            app.T_offButton_10.Text = 'off';
            app.V_offButton_10 = uibutton(app.GridLayout3, 'state');
            app.V_offButton_10.ValueChangedFcn = createCallbackFcn(app, @V_offButton_10ValueChanged, true);
            app.V_offButton_10.Text = 'off';
            app.T_offButton_11 = uibutton(app.GridLayout3, 'state');
            app.T_offButton_11.ValueChangedFcn = createCallbackFcn(app, @T_offButton_11ValueChanged, true);
            app.T_offButton_11.Text = 'off';
            app.V_offButton_11 = uibutton(app.GridLayout3, 'state');
            app.V_offButton_11.ValueChangedFcn = createCallbackFcn(app, @V_offButton_11ValueChanged, true);
            app.V_offButton_11.Text = 'off';
            app.T_offButton_12 = uibutton(app.GridLayout3, 'state');
            app.T_offButton_12.ValueChangedFcn = createCallbackFcn(app, @T_offButton_12ValueChanged, true);
            app.T_offButton_12.Text = 'off';
            app.V_offButton_12 = uibutton(app.GridLayout3, 'state');
            app.V_offButton_12.ValueChangedFcn = createCallbackFcn(app, @V_offButton_12ValueChanged, true);
            app.V_offButton_12.Text = 'off';
            app.OC_offButton_7 = uibutton(app.GridLayout3, 'state');
            app.OC_offButton_7.ValueChangedFcn = createCallbackFcn(app, @OC_offButton_7ValueChanged, true);
            app.OC_offButton_7.Text = 'off';
            app.SC_offButton_7 = uibutton(app.GridLayout3, 'state');
            app.SC_offButton_7.ValueChangedFcn = createCallbackFcn(app, @SC_offButton_7ValueChanged, true);
            app.SC_offButton_7.Text = 'off';
            app.OC_offButton_8 = uibutton(app.GridLayout3, 'state');
            app.OC_offButton_8.ValueChangedFcn = createCallbackFcn(app, @OC_offButton_8ValueChanged, true);
            app.OC_offButton_8.Text = 'off';
            app.SC_offButton_8 = uibutton(app.GridLayout3, 'state');
            app.SC_offButton_8.ValueChangedFcn = createCallbackFcn(app, @SC_offButton_8ValueChanged, true);
            app.SC_offButton_8.Text = 'off';
            app.OC_offButton_9 = uibutton(app.GridLayout3, 'state');
            app.OC_offButton_9.ValueChangedFcn = createCallbackFcn(app, @OC_offButton_9ValueChanged, true);
            app.OC_offButton_9.Text = 'off';
            app.SC_offButton_9 = uibutton(app.GridLayout3, 'state');
            app.SC_offButton_9.ValueChangedFcn = createCallbackFcn(app, @SC_offButton_9ValueChanged, true);
            app.SC_offButton_9.Text = 'off';
            app.OC_offButton_10 = uibutton(app.GridLayout3, 'state');
            app.OC_offButton_10.ValueChangedFcn = createCallbackFcn(app, @OC_offButton_10ValueChanged, true);
            app.OC_offButton_10.Text = 'off';
            app.SC_offButton_10 = uibutton(app.GridLayout3, 'state');
            app.SC_offButton_10.ValueChangedFcn = createCallbackFcn(app, @SC_offButton_10ValueChanged, true);
            app.SC_offButton_10.Text = 'off';
            app.OC_offButton_11 = uibutton(app.GridLayout3, 'state');
            app.OC_offButton_11.ValueChangedFcn = createCallbackFcn(app, @OC_offButton_11ValueChanged, true);
            app.OC_offButton_11.Text = 'off';
            app.SC_offButton_11 = uibutton(app.GridLayout3, 'state');
            app.SC_offButton_11.ValueChangedFcn = createCallbackFcn(app, @SC_offButton_11ValueChanged, true);
            app.SC_offButton_11.Text = 'off';
            app.OC_offButton_12 = uibutton(app.GridLayout3, 'state');
            app.OC_offButton_12.ValueChangedFcn = createCallbackFcn(app, @OC_offButton_12ValueChanged, true);
            app.OC_offButton_12.Text = 'off';
            app.SC_offButton_12 = uibutton(app.GridLayout3, 'state');
            app.SC_offButton_12.ValueChangedFcn = createCallbackFcn(app, @SC_offButton_12ValueChanged, true);
            app.SC_offButton_12.Text = 'off';
            app.SC_offButton_6 = uibutton(app.GridLayout3, 'state');
            app.SC_offButton_6.ValueChangedFcn = createCallbackFcn(app, @SC_offButton_6ValueChanged, true);
            app.SC_offButton_6.Text = 'off';
            app.OC_offButton_6 = uibutton(app.GridLayout3, 'state');
            app.OC_offButton_6.ValueChangedFcn = createCallbackFcn(app, @OC_offButton_6ValueChanged, true);
            app.OC_offButton_6.Text = 'off';
            app.SC_offButton_5 = uibutton(app.GridLayout3, 'state');
            app.SC_offButton_5.ValueChangedFcn = createCallbackFcn(app, @SC_offButton_5ValueChanged, true);
            app.SC_offButton_5.Text = 'off';
            app.OC_offButton_5 = uibutton(app.GridLayout3, 'state');
            app.OC_offButton_5.ValueChangedFcn = createCallbackFcn(app, @OC_offButton_5ValueChanged, true);
            app.OC_offButton_5.Text = 'off';
            app.SC_offButton_4 = uibutton(app.GridLayout3, 'state');
            app.SC_offButton_4.ValueChangedFcn = createCallbackFcn(app, @SC_offButton_4ValueChanged, true);
            app.SC_offButton_4.Text = 'off';
            app.OC_offButton_4 = uibutton(app.GridLayout3, 'state');
            app.OC_offButton_4.ValueChangedFcn = createCallbackFcn(app, @OC_offButton_4ValueChanged, true);
            app.OC_offButton_4.Text = 'off';
            app.SC_offButton_3 = uibutton(app.GridLayout3, 'state');
            app.SC_offButton_3.ValueChangedFcn = createCallbackFcn(app, @SC_offButton_3ValueChanged, true);
            app.SC_offButton_3.Text = 'off';
            app.OC_offButton_3 = uibutton(app.GridLayout3, 'state');
            app.OC_offButton_3.ValueChangedFcn = createCallbackFcn(app, @OC_offButton_3ValueChanged, true);
            app.OC_offButton_3.Text = 'off';
            app.SC_offButton_2 = uibutton(app.GridLayout3, 'state');
            app.SC_offButton_2.ValueChangedFcn = createCallbackFcn(app, @SC_offButton_2ValueChanged, true);
            app.SC_offButton_2.Text = 'off';
            app.OC_offButton_2 = uibutton(app.GridLayout3, 'state');
            app.OC_offButton_2.ValueChangedFcn = createCallbackFcn(app, @OC_offButton_2ValueChanged, true);
            app.OC_offButton_2.Text = 'off';
            app.SC_offButton_1 = uibutton(app.GridLayout3, 'state');
            app.SC_offButton_1.ValueChangedFcn = createCallbackFcn(app, @SC_offButton_1ValueChanged, true);
            app.SC_offButton_1.Text = 'off';
            app.OC_offButton_1 = uibutton(app.GridLayout3, 'state');
            app.OC_offButton_1.ValueChangedFcn = createCallbackFcn(app, @OC_offButton_1ValueChanged, true);
            app.OC_offButton_1.Text = 'off';
            app.StopTimeEditField = slrealtime.ui.control.StopTimeEditField(app.GridLayout);
            app.RealtimeSimStartButton = uibutton(app.GridLayout, 'state');
            app.RealtimeSimStartButton.ValueChangedFcn = createCallbackFcn(app, @RealtimeSimStartButtonValueChanged, true);
            app.RealtimeSimStartButton.Tooltip = {'Run on Target'};
            app.RealtimeSimStartButton.Icon = 'slrtRunIcon.png';
            app.RealtimeSimStartButton.Text = {'Start '; ''; '(On Target)'};
            app.StatusTextAreaLabel = uilabel(app.GridLayout);
            app.StatusTextAreaLabel.Text = 'Status:';
            app.StatusMessagesTextArea = uitextarea(app.GridLayout);
            app.StatusMessagesTextArea.Editable = 'off';
            app.StatusMessagesTextArea.Tooltip = {'Information bar'};
            app.StatusMessagesTextArea.Value = {'Battery Management System'};
            app.MILSimTime = uitextarea(app.GridLayout);
            app.MILSimTime.Editable = 'off';
            app.MILSimTime.Visible = 'off';
            app.MILSimTime.Value = {'T=0'};
            % Show the figure after all components are created
            app.BMSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BMSApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BMSUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BMSUIFigure)
        end
    end
end